classdef UserInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OptimizationGUIUIFigure         matlab.ui.Figure
        ElectricVehicleBatteryPanel     matlab.ui.container.Panel
        P_maxkWEditFieldLabel           matlab.ui.control.Label
        P_maxkWEditField                matlab.ui.control.NumericEditField
        P_minkWEditFieldLabel           matlab.ui.control.Label
        P_minkWEditField                matlab.ui.control.NumericEditField
        EVBatteryCapacitykWhEditFieldLabel  matlab.ui.control.Label
        EVBatteryCapacitykWhEditField   matlab.ui.control.NumericEditField
        SoC_initialkWhEditFieldLabel    matlab.ui.control.Label
        SoC_initialkWhEditField         matlab.ui.control.NumericEditField
        ConverterEfficiencyEditFieldLabel  matlab.ui.control.Label
        ConverterEfficiencyEditField    matlab.ui.control.NumericEditField
        CommutesettingsPanel            matlab.ui.container.Panel
        SoC_commutekWhEditFieldLabel    matlab.ui.control.Label
        SoC_commutekWhEditField         matlab.ui.control.EditField
        EVDeparturehoursinceoptimisationstartSpinnerLabel  matlab.ui.control.Label
        EVDeparturehoursinceoptimisationstartSpinner  matlab.ui.control.Spinner
        EVReturnhoursinceoptimisationstartSpinnerLabel  matlab.ui.control.Label
        EVReturnhoursinceoptimisationstartSpinner  matlab.ui.control.Spinner
        CommuteSwitchLabel              matlab.ui.control.Label
        CommuteSwitch                   matlab.ui.control.Switch
        SoC_targetkWhEditFieldLabel     matlab.ui.control.Label
        SoC_targetkWhEditField          matlab.ui.control.NumericEditField
        ElectricVehicleSwitchLabel      matlab.ui.control.Label
        ElectricVehicleSwitch           matlab.ui.control.Switch
        RegularBatteryPanel             matlab.ui.container.Panel
        P_maxkWEditField_2Label         matlab.ui.control.Label
        P_maxkWEditField_2              matlab.ui.control.NumericEditField
        P_minkWEditField_2Label         matlab.ui.control.Label
        P_minkWEditField_2              matlab.ui.control.NumericEditField
        CapacitykWhEditFieldLabel       matlab.ui.control.Label
        CapacitykWhEditField            matlab.ui.control.NumericEditField
        SoC_initialkWhEditField_2Label  matlab.ui.control.Label
        SoC_initialkWhEditField_2       matlab.ui.control.NumericEditField
        ConverterEfficiencyEditField_2Label  matlab.ui.control.Label
        ConverterEfficiencyEditField_2  matlab.ui.control.NumericEditField
        SoC_targetkWhEditField_2Label   matlab.ui.control.Label
        SoC_targetkWhEditField_2        matlab.ui.control.NumericEditField
        BatterySwitchLabel              matlab.ui.control.Label
        BatterySwitch                   matlab.ui.control.Switch
        SolarPanels                     matlab.ui.container.Panel
        SolarPanelsSwitchLabel          matlab.ui.control.Label
        SolarPanelsSwitch               matlab.ui.control.Switch
        RatedPowerkWEditFieldLabel      matlab.ui.control.Label
        RatedPowerkWEditField           matlab.ui.control.NumericEditField
        LoadShiftingPanel               matlab.ui.container.Panel
        LoadShiftingEditFieldLabel      matlab.ui.control.Label
        LoadShiftingEditField           matlab.ui.control.NumericEditField
        ShiftingSwitchLabel             matlab.ui.control.Label
        ShiftingSwitch                  matlab.ui.control.Switch
        GridPanel                       matlab.ui.container.Panel
        P_grid_maxkWEditFieldLabel      matlab.ui.control.Label
        P_grid_maxkWEditField           matlab.ui.control.NumericEditField
        P_grid_minkWEditFieldLabel      matlab.ui.control.Label
        P_grid_minkWEditField           matlab.ui.control.NumericEditField
        ThermodynamicModelPanel         matlab.ui.container.Panel
        AirDensitykgm3EditFieldLabel    matlab.ui.control.Label
        AirDensitykgm3EditField         matlab.ui.control.NumericEditField
        HeatCapacityCkJkgCEditFieldLabel  matlab.ui.control.Label
        HeatCapacityCkJkgCEditField     matlab.ui.control.NumericEditField
        AirVolumeVm3EditFieldLabel      matlab.ui.control.Label
        AirVolumeVm3EditField           matlab.ui.control.NumericEditField
        U_wallkWm2CEditFieldLabel       matlab.ui.control.Label
        U_wallkWm2CEditField            matlab.ui.control.NumericEditField
        U_windowkWm2CEditFieldLabel     matlab.ui.control.Label
        U_windowkWm2CEditField          matlab.ui.control.NumericEditField
        WallSurfacem2EditFieldLabel     matlab.ui.control.Label
        WallSurfacem2EditField          matlab.ui.control.NumericEditField
        WindowSurfacem2EditFieldLabel   matlab.ui.control.Label
        WindowSurfacem2EditField        matlab.ui.control.NumericEditField
        awEditFieldLabel                matlab.ui.control.Label
        awEditField                     matlab.ui.control.NumericEditField
        R_sejm2CWEditFieldLabel         matlab.ui.control.Label
        R_sejm2CWEditField              matlab.ui.control.NumericEditField
        windowEditFieldLabel            matlab.ui.control.Label
        windowEditField                 matlab.ui.control.NumericEditField
        SCEditFieldLabel                matlab.ui.control.Label
        SCEditField                     matlab.ui.control.NumericEditField
        HeatingCoolingsystemPanel       matlab.ui.container.Panel
        InitialIndoorTemperatureCEditFieldLabel  matlab.ui.control.Label
        InitialIndoorTemperatureCEditField  matlab.ui.control.NumericEditField
        MaxIndoorTemperatureCEditFieldLabel  matlab.ui.control.Label
        MaxIndoorTemperatureCEditField  matlab.ui.control.NumericEditField
        MinIndoorTemperatureCEditFieldLabel  matlab.ui.control.Label
        MinIndoorTemperatureCEditField  matlab.ui.control.NumericEditField
        MaxHeatingCoolingPowerkWLabel   matlab.ui.control.Label
        MaxHeatingCoolingPowerkWEditField  matlab.ui.control.NumericEditField
        MinHeatingCoolingPowerkWEditFieldLabel  matlab.ui.control.Label
        MinHeatingCoolingPowerkWEditField  matlab.ui.control.NumericEditField
        COPEditFieldLabel               matlab.ui.control.Label
        COPEditField                    matlab.ui.control.NumericEditField
        HeatingSystemSwitchLabel        matlab.ui.control.Label
        HeatingSystemSwitch             matlab.ui.control.Switch
        AdvancedSettingsCheckBox        matlab.ui.control.CheckBox
        SerialCommunicationPanel        matlab.ui.container.Panel
        ConnectionstatusLampLabel       matlab.ui.control.Label
        SerialConnectionstatusLamp      matlab.ui.control.Lamp
        SerialConnectButton             matlab.ui.control.Button
        SerialDisconnectButton          matlab.ui.control.Button
        COMEditFieldLabel               matlab.ui.control.Label
        COMEditField                    matlab.ui.control.EditField
        BaudDropDownLabel               matlab.ui.control.Label
        BaudDropDown                    matlab.ui.control.DropDown
        WifiCommunicationPanel          matlab.ui.container.Panel
        ConnectionstatusLamp_2Label     matlab.ui.control.Label
        WIFIConnectionstatusLamp        matlab.ui.control.Lamp
        WIFIConnectButton               matlab.ui.control.Button
        WIFIDisconnectButton            matlab.ui.control.Button
        IPEditFieldLabel                matlab.ui.control.Label
        IPEditField                     matlab.ui.control.EditField
        PortEditFieldLabel              matlab.ui.control.Label
        PortEditField                   matlab.ui.control.NumericEditField
        gui_status                      matlab.ui.control.Label
        RunOptimizationButton           matlab.ui.control.Button
        PLabel                          matlab.ui.control.Label
        PPV_val                         matlab.ui.control.Label
        PVLabel                         matlab.ui.control.Label
        PLabel_2                        matlab.ui.control.Label
        PSC_val                         matlab.ui.control.Label
        SCLabel                         matlab.ui.control.Label
        PLabel_3                        matlab.ui.control.Label
        PBat_val                        matlab.ui.control.Label
        BATLabel                        matlab.ui.control.Label
        PLabel_4                        matlab.ui.control.Label
        PEV_val                         matlab.ui.control.Label
        EVLabel                         matlab.ui.control.Label
        PLabel_5                        matlab.ui.control.Label
        Pgrid_val                       matlab.ui.control.Label
        GRIDLabel                       matlab.ui.control.Label
        SoCLabel                        matlab.ui.control.Label
        BATLabel_2                      matlab.ui.control.Label
        SoCLabel_2                      matlab.ui.control.Label
        EVLabel_2                       matlab.ui.control.Label
        SoC_bat_val                     matlab.ui.control.Label
        SoC_ev_val                      matlab.ui.control.Label
        PlotOptimizationButton          matlab.ui.control.Button
        AdvancedSettingsPanel           matlab.ui.container.Panel
        PSOParametersPanel              matlab.ui.container.Panel
        PSOParticlesEditFieldLabel      matlab.ui.control.Label
        PSOParticlesEditField           matlab.ui.control.NumericEditField
        PSOEpochsEditFieldLabel         matlab.ui.control.Label
        PSOEpochsEditField              matlab.ui.control.NumericEditField
        SavefolderEditFieldLabel        matlab.ui.control.Label
        SavefolderEditField             matlab.ui.control.EditField
        IPofdataaggregatorEditFieldLabel  matlab.ui.control.Label
        IPofdataaggregatorEditField     matlab.ui.control.EditField
        Vdc_val                         matlab.ui.control.Label
        VLabel                          matlab.ui.control.Label
        DCLabel                         matlab.ui.control.Label
        Button_2                        matlab.ui.control.Button
    end

    
    properties (Access = public)
        
        
        s % serial port
        s2
        
        client % wifi client
        baudrate
        ip
        port
        
        timerObject % timer (for saving data to files)
        
        CostOfPV
        PV_Production
        SoC_initial_ev
        SoC_commute
        Tmax
        Tmin
        T_in_initial
        Alpha
        Beta
        Zeta
        SoC_target_ev
        dt
        V
        C
        rho
        Uwall
        Uwindow
        Fwall
        Fwindow
        aw
        Rsej
        taf_window
        SC
        Qin
        Qsw
        Qsg
        QEC_max
        QEC_min
        COP
        dim1
        dim2
        dim3
        dim4
        heatcool_sign
        SoC_initial_bat
        SoC_target_bat
        EV_bat_n
        bat_n
        scenario
        solar_panels_state
        solar_panel_power
        Pmax_ev
        Pmin_ev
        Pmax_bat
        Pmin_bat
        Load_shifting
        P_grid_max
        P_grid_min
        U
        U_initial
        ps
        epoch
        OptimisationStartTime
        t_leave
        t_return
        EV_flag
        Battery_flag
        HeatSystem_flag
        Loadshift_flag
        Ev_bat_capacity
        bat_capacity
        commute_enable
        PPEV_opt
        PBAT_opt
        QEC_opt
        indoor_temperature_opt
        state_of_charge_ev_opt
        state_of_charge_bat_opt
        PLOAD_opt
        SoCmax_ev
        SoCmin_ev
        SoCmax_bat
        SoCmin_bat
        
        Iev
        Vev
        ISC
        VSC
        Ibat
        Vbat
        IPV
        VPV
        Ia_rms
        Ib_rms
        Ic_rms
        Va_rms
        Vb_rms
        Vc_rms
        Vdc
        SoC_ev
        SoC_bat
        
        Pev
        Pbat
        PPV
        PSC
        PGRID
        
        
        filename_RT_VI
        fileID_RT_VI
        filename_RT_P
        fileID_RT_P
    end
    
    methods (Access = public)
        
        function [] = run_optimization_serial(app)
            close all;
            %             %% ------------General Initialization -----------------------
            %
            %             clc
            %
            %             start_signal = 21474836;
            %             end_signal = 21374332;
            %
            %             app.gui_status.Text = "Writing GUI parameters...";
            %
            %
            %             write(app.s,[start_signal app.ps app.epoch app.dt],"single")
            %             pause(0.3)
            %             write(app.s,[app.dim1 app.dim2 app.dim3 app.dim4],"single")     %TODO: check if dims are correctly assigned
            %             pause(0.3)
            %             write(app.s,[app.Pmax_ev app.Pmin_ev app.EV_bat_n app.SoC_initial_ev],"single")
            %             pause(0.3)
            %             write(app.s,[app.SoC_target_ev app.Pmax_bat app.Pmin_bat app.SoC_initial_bat],"single")
            %             pause(0.3)
            %             write(app.s,[app.SoC_target_bat app.bat_capacity app.bat_n app.Tmax],"single")
            %             pause(0.3)
            %             write(app.s,[app.Tmin app.T_in_initial app.V app.C],"single")
            %             pause(0.3)
            %             write(app.s,[app.rho app.Uwall app.Uwindow app.Fwall],"single")
            %             pause(0.3)
            %             write(app.s,[app.Fwindow app.aw app.Rsej app.taf_window],"single")
            %             pause(0.3)
            %             write(app.s,[app.SC app.QEC_max app.QEC_min app.COP],"single")
            %             pause(0.3)
            %             write(app.s,[app.Ev_bat_capacity 0 0 0],"single")
            %             pause(0.3)
            %             write(app.s,[end_signal 0 0 0],"single")
            %             pause(0.3)
            %             %             write(app.s,[app. app. app. app.],"single")
            %             %             pause(0.1)
            %
            %
            %             read_flag = 0;
            %             counter = -1;
            %             finish_flag = 1;
            %
            %             while (finish_flag && isvalid(app.s))
            %                 app.gui_status.Text = "Reading from GUI...";
            %
            %                 read_thing = read(app.s,1,"single")
            %                 %                 read_thing = read(app.s,4,"single")
            %                 %                 read_thing = read(app.s,2,"single")
            %                 %                 read_thing = read(app.s,1,"int32")
            %
            %                 if read_thing == 1234567
            %                     read_flag = 1;
            %                     fprintf('\n');
            %                 end
            %
            %                 if read_flag == 1
            %                     fprintf('%1.1f ',read_thing);
            %                     counter = counter + 1;
            %                     if counter >=1
            %                         saved_array(counter) = read_thing;
            %                     end
            %                     if counter>=343
            %                         finish_flag = 0;
            %                     end
            %                 else
            %                     fprintf('\n.');
            %                 end
            %
            %
            %
            %                 pause(0.0001)
            %             end
            %
            %             fprintf('\n\n End');
            %
            %             PPEV = single(zeros(1,49));
            %             state_of_charge_ev = single(zeros(1,49));
            %             QEC = single(zeros(1,49));
            %             indoor_temperature = single(zeros(1,49));
            %             PBAT = single(zeros(1,49));
            %             state_of_charge_bat = single(zeros(1,49));
            %             PLOAD = single(zeros(1,49));
            
        end
        
    end
    
    methods (Access = public)
        
        function [] = run_optimization_wifi(app)
            close all;
            %             clc
            %% ------------General Initialization -----------------------
            
            if app.EV_flag=="Off"
                app.Pmax_ev=0;
                app.Pmin_ev=0;
            end
            if app.Battery_flag=="Off"
                app.Pmax_bat=0;
                app.Pmin_bat=0;
            end
            if app.HeatSystem_flag=="Off"
                app.QEC_max = 0;
                app.QEC_min = 0;
            end
            
            
            GUI_params_write = convertStringsToChars(sprintf("SG%d,%d,%d,%d,%d,%d,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f," + ...
                "%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2fE", ...
                app.ps,app.epoch,app.dim1,app.dim2,app.dim3,app.dim4,app.Pmax_ev,app.Pmin_ev,app.EV_bat_n,app.SoC_initial_ev, ...
                app.SoC_target_ev,app.Pmax_bat,app.Pmin_bat,app.SoC_initial_bat,app.SoC_target_bat,app.bat_capacity,app.bat_n, ...
                app.Tmax,app.Tmin,app.T_in_initial,app.V,app.C,app.rho,app.Uwall,app.Uwindow,app.Fwall,app.Fwindow,app.aw,app.Rsej, ...
                app.taf_window,app.SC,app.QEC_max,app.QEC_min,app.COP,app.Ev_bat_capacity)); %180 bits
            %
            %             GUI_params_write = convertStringsToChars(sprintf("SG1.1,2.2,3.3,4,5,6,7,8,9,10,11,12,13,14,15,16,17,18,19,20,21,22,23,24,25,26,27,28,29,30,31,32,33,34,35E"));
            
            CostOfPower = ([0.1017,0.0982,0.0946,0.0888,0.0829,0.0794,0.0759,0.0754,0.0748,0.0748,0.0748,0.0753,0.0758,0.0866,0.0974,0.1055,0.1136,0.1150,0.1165,0.1179,0.1194,0.1171,0.1148,0.1147,0.1147,0.1138,0.1130,0.1092,0.1055,0.1036,0.1017,0.1014,0.1011,0.1013,0.1015,0.1016,0.1016,0.1018,0.1019,0.1018,0.1016,0.1018,0.1020,0.1019,0.1019,0.1019,0.1020,0.1008,0.0996]);
            CostOfPower_write = convertStringsToChars(sprintf("SC%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f," + ...
                "%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f," + ...
                "%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3fE", CostOfPower(1:49)));  %296 bits
            
            %             CostOfPower_write = convertStringsToChars(sprintf("SC-1.1,-2.2,-3.3,4,5,6,7,8,9,10,11,12,13,14,15,16,17,18,19,20,21,22,23,24,25,26,27,28,29,30,31,32,33,34,35,36,37,38,39,40,41,42,43,44,45,46,47,48,49E"));
            %             CostOfPower_write = convertStringsToChars(sprintf("SC1.....,2.....,3.....,4.....,5.....,6.....,7.....,8.....,9.....,10.....,11.....,12.....,13.....,14.....,15.....,16.....,17.....,18.....,19.....,20.....,21.....,22.....,23.....,24.....,25.....,26.....,27.....,28.....,29.....,30.....,31.....,32.....,33.....,34.....,35.....,36.....,37.....,38.....,39.....,40.....,41.....,42.....,43.....,44.....,45.....,46.....,47.....,48.....,49E"));
            
            home_load=[0.20,0.20,0.20,0.20,0.20,0.20,0.20,0.20,0.20,0.20,0.20,0.20,0.20,0.20,1.90,1,0.20,0.20,0.20,0.20,0.20,0.20,0.20,0.20,0.20,0.20,0.20,0.20,0.20,0.20,0.20,0.20,0.20,0.20,0.20,0.20,1.10,1.90,1.30,1.30,0.750,0.750,0.750,0.650,0.650,0.50,0.50,0.50,0.50];
            home_load_write = convertStringsToChars(sprintf("SL%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f," + ...
                "%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f," + ...
                "%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2fE", home_load(1:49)));  %247 bits
            
            solar_radiation = single([0.00176768359429285,0.00230675181073822,0.00304718455033354,0.00407929122314998,0.00554121631583648,0.00764839777643748,0.0107437329283340,0.0153846153846154,0.0224962839304546,0.0336440921598423,0.0515123641346265,0.0807061790668348,0.128899717510652,0.207697378429086,0.330328824259531,0.500,0.690228624540210,0.848911917098446,0.943748222443829,0.984615384615385,0.997226797731224,0.999755918965096,0.999996185317286,1,0.999996185317286,0.999755918965096,0.997226797731224,0.984615384615385,0.943748222443829,0.848911917098446,0.690228624540210,0.500,0.330328824259531,0.207697378429086,0.128899717510652,0.0807061790668348,0.0515123641346265,0.0336440921598423,0.0224962839304546,0.0153846153846154,0.0107437329283340,0.00764839777643748,0.00554121631583648,0.00407929122314998,0.00304718455033354,0.00230675181073822,0.00176768359429285,0.00136986301369863,0.00107259013910763]);
            solar_radiation_write = convertStringsToChars(sprintf("SR%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f," + ...
                "%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f," + ...
                "%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3f,%.3fE", solar_radiation(1:49)));  %296 bits
            
            outdoor_temperature = single([26.9978988733706,26.5091729050326,26.0782825403330,25.7123667190317,25.4174878904655,25.1985315716153,25.0591254044486,25.0015790535835,25.0268459400428,25.1345074450894,25.3227798458507,25.5885438678252,25.9273963646475,26.3337232688872,26.8007926052441,27.3208660251050,27.8853270145735,28.4848236518361,29.1094235486789,29.7487784091012,30.3922954786392,31.0293130438494,31.6492770742943,32.2419160804502,32.7974112905108,33.3065593266183,33.7609246853206,34.1529794959791,34.4762282416241,34.7253153758990,34.8961140531088,34.9857945013031,34.9928709055976,34.9172260249767,34.7601131347284,34.5241352623301,34.2132020608022,33.8324650340468,33.3882321873505,32.8878635171113,32.3396490713029,31.7526716009563,31.1366560782332,30.5018085742561,30,29.3577528489985,28.7261463715831,28.1156449479392,27.5363632922585]);
            outdoor_temperature_write = convertStringsToChars(sprintf("ST%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f," + ...
                "%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f," + ...
                "%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2f,%.2fE", outdoor_temperature(1:49)));  %296 bits
            
            app.gui_status.Text = "Writing GUI parameters...";
            
            cDate = date;
            
            filename_O_I = append("Optimization_Inputs_",cDate,".txt");
            
            fileID_O_I = fopen(filename_O_I,'w');
            fprintf(fileID_O_I,'%s\t\t\t|\t%s\t|\t%s\t|\t%s|\t%s\n','Date and Time', ...
                'Tout (°C)','Ppv (kW)','Cost (€/kWh)','Pload (kW)');
            
            currentDate = datetime('now');
            for i = 1:49
                
                fprintf(fileID_O_I,['%s\t|\t%8.4f\t|\t%8.4f\t|\t%8.4f\t|\t%8.4f\t\n'], ...
                    datestr(currentDate),outdoor_temperature(i),solar_radiation(i),CostOfPower(i),home_load(i));
                
                currentDate = currentDate + minutes(30);
                
            end
            
            fclose(fileID_O_I);
            
            write (app.client,uint8(GUI_params_write))
            pause(0.5)
            write (app.client,uint8(CostOfPower_write))
            pause(0.5)
            write (app.client,uint8(home_load_write))
            pause(0.5)
            write (app.client,uint8(solar_radiation_write))
            pause(0.5)
            write (app.client,uint8(outdoor_temperature_write))
            %
            
        end
    end
    methods (Access = public)
        
        function timerFunction(app, ~, ~)
            
            disp("timer called")
            clockFull = clock;
            currentTime = sprintf('%d-%02d-%02d,%02d:%02d:%02.0f',clockFull(1:5),clockFull(6));
             
            if ~isnan(app.Vev)
                fprintf(app.fileID_RT_VI,['%s\t|\t%8.4f\t|\t%6.4f\t|\t%8.4f\t|\t%6.4f\t|\t%8.4f\t|\t%6.4f\t|\t%8.4f\t|\t%6.4f\t|' ...
                    '\t%8.4f\t|\t%8.4f\t|\t%8.4f\t|\t%6.4f\t|\t%6.4f\t|\t%6.4f\t|\t%8.4f\t\n'], ...
                    currentTime,app.Vev,app.Iev,app.Vbat,app.Ibat,app.VSC,app.ISC,app.VPV,app.IPV,app.Va_rms,app.Vb_rms,app.Vc_rms, ...
                    app.Ia_rms,app.Ib_rms,app.Ic_rms,app.Vdc);
                
                
                fprintf(app.fileID_RT_P,['%s\t|\t%8.4f\t|\t%8.4f\t|\t%8.4f\t|\t%8.4f\t|\t%8.4f\t|\t%8.4f\t|\t%8.4f\n'], ...
                    currentTime,app.Pev,app.Pbat,app.SoC_ev,app.SoC_bat,app.PPV,app.PSC,app.PGRID);

            end
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            close all;
            %             app.scenario=app.ScenarioSelectionDropDown.Value;
            app.gui_status.Text = "Not connected...";
            
            
            % set up timer
            app.timerObject = timer;
            app.timerObject.TimerFcn = @app.timerFunction;
            app.timerObject.ExecutionMode = 'fixedRate';
            app.timerObject.Period = 10;
            app.timerObject.BusyMode = 'queue';
            
            %may need to fix
            app.CostOfPV = 0.065;
            app.OptimisationStartTime = 1;
            app.dt = 0.5;
            app.dim1 = 13;
            app.dim2 = 13;
            app.dim3 = 13;
            app.dim4 = 13;
            
            %may need to fix
            
            app.PlotOptimizationButton.Enable ="Off";
            
            app.baudrate = str2double(app.BaudDropDown.Value);
            app.ip = app.IPEditField.Value;
            app.port = app.PortEditField.Value;
            
            app.solar_panels_state=app.SolarPanelsSwitch.Value;
            if app.SolarPanelsSwitch.Value=="Off"
                app.RatedPowerkWEditField.Enable="Off";
            end
            if app.SolarPanelsSwitch.Value=="On"
                app.RatedPowerkWEditField.Enable="On";
            end
            app.solar_panel_power=app.RatedPowerkWEditField.Value;
            app.Pmax_ev = app.P_maxkWEditField.Value;
            app.Pmin_ev = app.P_minkWEditField.Value;
            app.SoC_initial_ev = app.SoC_initialkWhEditField.Value;
            app.EV_bat_n = app.ConverterEfficiencyEditField.Value;
            app.SoC_target_ev = app.SoC_targetkWhEditField.Value;
            app.SoC_commute = str2double(app.SoC_commutekWhEditField.Value);
            app.Pmax_bat = app.P_maxkWEditField_2.Value;
            app.Pmin_bat = app.P_minkWEditField_2.Value;
            app.SoC_initial_bat = app.SoC_initialkWhEditField_2.Value;
            app.SoC_target_bat = app.SoC_targetkWhEditField_2.Value;
            app.bat_n = app.ConverterEfficiencyEditField_2.Value;
            app.Load_shifting = app.LoadShiftingEditField.Value;
            app.P_grid_max = app.P_grid_maxkWEditField.Value;
            app.P_grid_min = app.P_grid_minkWEditField.Value;
            app.V = app.AirDensitykgm3EditField.Value;
            app.C = app.HeatCapacityCkJkgCEditField.Value;
            app.rho = app.AirVolumeVm3EditField.Value;
            app.aw = app.awEditField.Value;
            app.Rsej = app.R_sejm2CWEditField.Value;
            app.SC = app.SCEditField.Value;
            app.Uwall = app.U_wallkWm2CEditField.Value;
            app.Uwindow = app.U_windowkWm2CEditField.Value;
            app.Fwall = app.WallSurfacem2EditField.Value;
            app.Fwindow = app.WindowSurfacem2EditField.Value;
            app.taf_window = app.windowEditField.Value;
            app.T_in_initial = app.InitialIndoorTemperatureCEditField.Value;
            app.Tmax = app.MaxIndoorTemperatureCEditField.Value;
            app.Tmin = app.MinIndoorTemperatureCEditField.Value;
            app.QEC_max = app.MaxHeatingCoolingPowerkWEditField.Value;
            app.QEC_min = app.MinHeatingCoolingPowerkWEditField.Value;
            app.COP = app.COPEditField.Value;
            app.ps = app.PSOParticlesEditField.Value;
            app.epoch = app.PSOEpochsEditField.Value;
            app.t_leave = 2*app.EVDeparturehoursinceoptimisationstartSpinner.Value+1;
            app.t_return = 2*app.EVReturnhoursinceoptimisationstartSpinner.Value+1;
            app.EV_flag = app.ElectricVehicleSwitch.Value;
            app.Battery_flag = app.BatterySwitch.Value;
            if app.BatterySwitch.Value == "Off"
                app.P_maxkWEditField_2.Enable="Off";
                app.P_minkWEditField_2.Enable="Off";
                app.CapacitykWhEditField.Enable="Off";
                app.SoC_initialkWhEditField_2.Enable="Off";
                app.SoC_targetkWhEditField_2.Enable="Off";
                app.ConverterEfficiencyEditField_2.Enable="Off";
            end
            if app.BatterySwitch.Value == "On"
                app.P_maxkWEditField_2.Enable="On";
                app.P_minkWEditField_2.Enable="On";
                app.CapacitykWhEditField.Enable="On";
                app.SoC_initialkWhEditField_2.Enable="On";
                app.SoC_targetkWhEditField_2.Enable="On";
                app.ConverterEfficiencyEditField_2.Enable="On";
            end
            if app.ElectricVehicleSwitch.Value=="Off"
                app.P_maxkWEditField.Enable="Off";
                app.P_minkWEditField.Enable="Off";
                app.EVBatteryCapacitykWhEditField.Enable="Off";
                app.SoC_initialkWhEditField.Enable="Off";
                app.SoC_targetkWhEditField.Enable="Off";
                app.ConverterEfficiencyEditField.Enable="Off";
                app.SoC_commutekWhEditField.Enable="Off";
                app.EVReturnhoursinceoptimisationstartSpinner.Enable="Off";
                app.EVDeparturehoursinceoptimisationstartSpinner.Enable="Off";
            end
            if app.ElectricVehicleSwitch.Value=="On"
                app.P_maxkWEditField.Enable="On";
                app.P_minkWEditField.Enable="On";
                app.EVBatteryCapacitykWhEditField.Enable="On";
                app.SoC_initialkWhEditField.Enable="On";
                app.SoC_targetkWhEditField.Enable="On";
                app.ConverterEfficiencyEditField.Enable="On";
                app.SoC_commutekWhEditField.Enable="On";
                app.EVReturnhoursinceoptimisationstartSpinner.Enable="On";
                app.EVDeparturehoursinceoptimisationstartSpinner.Enable="On";
            end
            app.Loadshift_flag = app.ShiftingSwitch.Value;
            if app.ShiftingSwitch.Value == "Off"
                app.LoadShiftingEditField.Enable="Off";
            end
            if app.ShiftingSwitch.Value == "On"
                app.LoadShiftingEditField.Enable="On";
            end
            app.HeatSystem_flag = app.HeatingSystemSwitch.Value;
            if app.HeatingSystemSwitch.Value=="Off"
                app.AirDensitykgm3EditField.Enable="Off";
                app.HeatCapacityCkJkgCEditField.Enable="Off";
                app.AirVolumeVm3EditField.Enable="Off";
                app.awEditField.Enable="Off";
                app.R_sejm2CWEditField.Enable="Off";
                app.U_wallkWm2CEditField.Enable="Off";
                app.U_windowkWm2CEditField.Enable="Off";
                app.WallSurfacem2EditField.Enable="Off";
                app.WindowSurfacem2EditField.Enable="Off";
                app.windowEditField.Enable="Off";
                app.SCEditField.Enable="Off";
                app.InitialIndoorTemperatureCEditField.Enable="Off";
                app.MaxIndoorTemperatureCEditField.Enable="Off";
                app.MinIndoorTemperatureCEditField.Enable="Off";
                app.MaxHeatingCoolingPowerkWEditField.Enable="Off";
                app.MinHeatingCoolingPowerkWEditField.Enable="Off";
                app.COPEditField.Enable="Off";
            end
            if app.HeatingSystemSwitch.Value=="On"
                app.AirDensitykgm3EditField.Enable="On";
                app.HeatCapacityCkJkgCEditField.Enable="On";
                app.AirVolumeVm3EditField.Enable="On";
                app.awEditField.Enable="On";
                app.R_sejm2CWEditField.Enable="On";
                app.U_wallkWm2CEditField.Enable="On";
                app.U_windowkWm2CEditField.Enable="On";
                app.WallSurfacem2EditField.Enable="On";
                app.WindowSurfacem2EditField.Enable="On";
                app.windowEditField.Enable="On";
                app.SCEditField.Enable="On";
                app.InitialIndoorTemperatureCEditField.Enable="On";
                app.MaxIndoorTemperatureCEditField.Enable="On";
                app.MinIndoorTemperatureCEditField.Enable="On";
                app.MaxHeatingCoolingPowerkWEditField.Enable="On";
                app.MinHeatingCoolingPowerkWEditField.Enable="On";
                app.COPEditField.Enable="On";
            end
            
            
            app.AdvancedSettingsPanel.Visible="Off";
            app.ThermodynamicModelPanel.Visible="On";
            
            app.Ev_bat_capacity = app.EVBatteryCapacitykWhEditField.Value;
            app.bat_capacity = app.CapacitykWhEditField.Value;
            
            app.SoCmax_ev = app.Ev_bat_capacity*0.9;
            app.SoCmin_ev = app.Ev_bat_capacity*0.1;
            app.SoCmax_bat = app.bat_capacity*0.9;
            app.SoCmin_bat = app.bat_capacity*0.1;
            
            app.commute_enable = app.CommuteSwitch.Value;
            if app.CommuteSwitch.Value == "Off"
                app.SoC_commutekWhEditField.Enable="Off";
                app.EVReturnhoursinceoptimisationstartSpinner.Enable="Off";
                app.EVDeparturehoursinceoptimisationstartSpinner.Enable="Off";
            elseif app.CommuteSwitch.Value == "On"
                app.SoC_commutekWhEditField.Enable="On";
                app.EVReturnhoursinceoptimisationstartSpinner.Enable="On";
                app.EVDeparturehoursinceoptimisationstartSpinner.Enable="On";
            end
            
            
            app.SerialConnectionstatusLamp.Color = [0.4 0.6 0.7]; %gray
            app.SerialDisconnectButton.Enable ="Off";
            
            app.WIFIConnectionstatusLamp.Color = [0.4 0.6 0.7]; %gray
            app.WIFIDisconnectButton.Enable ="Off";
            app.gui_status.Text = "Not connected.";
            
            app.IPV = NaN;
            app.VPV = NaN;
            app.ISC = NaN;
            app.VSC = NaN;
            app.Ibat = NaN;
            app.Vbat = NaN;
            app.Iev = NaN;
            app.Vev = NaN;
            app.Ia_rms = NaN;
            app.Ib_rms = NaN;
            app.Ic_rms = NaN;
            app.Va_rms = NaN;
            app.Vb_rms = NaN;
            app.Vc_rms = NaN;
            app.Vdc = NaN;
            app.SoC_ev= NaN;
            app.SoC_bat= NaN;
            
            
        end

        % Button pushed function: SerialConnectButton
        function SerialConnectButtonPushed(app, event)
            
            
            app.gui_status.Text = "Initializing serial connection...";
            clc
            app.SerialDisconnectButton.Enable ="On";
            app.SerialConnectButton.Enable ="Off";
            app.SerialConnectionstatusLamp.Color = [0 1 0]; %green
            app.WIFIConnectButton.Enable ="Off";
            
            com_port = append("COM",app.COMEditField.Value);
            app.s = serialport(com_port,app.baudrate);
            
            app.gui_status.Text = "Initializing optimization...";
            
            %             while (isvalid(app.s)==1)
            
            run_optimization_serial(app)
            
            
            %             end
            
            
        end

        % Button pushed function: SerialDisconnectButton
        function SerialDisconnectButtonPushed(app, event)
            app.SerialDisconnectButton.Enable ="Off";
            app.SerialConnectButton.Enable ="On";
            delete(app.s);
            delete(instrfind);
            app.SerialConnectionstatusLamp.Color = [1 0 0]; %red

            app.WIFIConnectButton.Enable ="On";
        end

        % Button pushed function: WIFIConnectButton
        function WIFIConnectButtonPushed(app, event)
            
            app.gui_status.Text = "Initializing wifi connection...";
            clc
            app.WIFIDisconnectButton.Enable ="On";
            app.WIFIConnectButton.Enable ="Off";
            app.WIFIConnectionstatusLamp.Color = [0 1 0]; %green
            app.SerialConnectButton.Enable ="Off";
            opt_text_expected_id = -100;
            
            currentDate = date;
            app.filename_RT_VI = append("RealTime_VoltageCurrent_",currentDate,".txt");
            app.filename_RT_P = append("RealTime_PowerSoC_",currentDate,".txt");

            if isfile(app.filename_RT_VI)
                app.fileID_RT_VI = fopen(app.filename_RT_VI,'a+');
                app.fileID_RT_P = fopen(app.filename_RT_P,'a+');
            else
                app.fileID_RT_VI = fopen(app.filename_RT_VI,'a+');
                fprintf(app.fileID_RT_VI,'%s\t\t|\t%s\t\t|\t%s\t|\t%s\t|\t%s|\t%s\t\t|\t%s\t|\t%s\t\t|\t%s\t|\t%s\t|\t%s\t|\t%s\t|\t%s|\t%s|\t%s|\t%s\t\n','Date and Time', ...
                    'Vev (V)','Iev (A)','Vbat (V)','Ibat (A)','Vsc (V)','Isc (A)','Vpv (V)','Ipv (A)','Vg,a (V)','Vg,b (V)','Vg,c (V)', ...
                    'Ig,a (A)','Ig,b (A)','Ig,c (A)','Vdc (V)');
                
                app.fileID_RT_P = fopen(app.filename_RT_P,'a+');
                fprintf(app.fileID_RT_P,'%s\t\t|\t%s\t|\t%s\t|\t%s\t|\t%s\t|\t%s\t|\t%s\t|\t%s\n','Date and Time', ...
                    'Pev (kW)','Pbat (kW)','SoCev (%)','SoCbat (%)','Ppv (kW)','Psc (kW)','Pgrid (kW)');

            end
            
            start(app.timerObject);
            
            
            app.client = tcpclient(app.IPEditField.Value,app.PortEditField.Value);
            
            % Serial for debugging only
            com_port = append("COM",app.COMEditField.Value);
            app.s = serialport(com_port,app.baudrate);
            app.s2 = serialport("COM10",app.baudrate);
            % Serial for debugging only
            
            app.gui_status.Text = "Initializing loop...";
            
            while (isvalid(app.client)==1)
                
                
%                 read_thing_serial = read(app.s2,11,"uint8");
%                 read_thing_serial = read(app.s2,4,"single")
%                 
%                 fprintf('CPU2: %s\n', char(read_thing_serial))
%                 read_thing_serial = read(app.s,11,"uint8");
                read_thing_serial = read(app.s,11,"uint8");
                fprintf('PSO: %s\n', char(read_thing_serial))
%                 read_thing_serial = read(app.s,5,"single");
%                 read_thing_serial = read(app.s,4,"single")
%                 for y = 1:size(read_thing_serial,2)
%                     fprintf('%.2f ',read_thing_serial(y));
%                 end
%                 fprintf('\n');


                if (app.client.BytesAvailable >0)
                    
                    app.gui_status.Text = "Reading wifi...";
                    wifi_data = char(read(app.client,250));
                    fprintf('-%s\n', wifi_data)
                    
                    
                    split_string = strsplit(wifi_data,',');
                    first_string_ascii = uint8(cell2mat( split_string(1)));
                    
                    if first_string_ascii(1:9)== [83 90 49 49 49 49 49 0 97]   %SZ11111 a, first optimization result
                        
                        opt_text_expected_id = 97; % a
                    end
                    
                    if first_string_ascii(1:9) == [83 56 56 56 56 56 56 0 0]   %S88888 - Real time values
                        D = uint8(char(split_string(19)));
                        E = D(1:12);
                        F=E>0;
                        Z = single(str2num(char(E(F))));
                        
                        read_thing(1:17) = single(str2num(char(split_string(2:18))));
                        read_thing(18) = Z;
                        read_thing
                        app.IPV = read_thing(1);
                        app.VPV = read_thing(2);
                        app.ISC = read_thing(3);
                        app.VSC = read_thing(4);
                        app.Ibat = read_thing(5);
                        app.Vbat = read_thing(6);
                        app.Iev = read_thing(7);
                        app.Vev = read_thing(8);
                        app.Ia_rms = read_thing(9);
                        app.Ib_rms = read_thing(10);
                        app.Ic_rms = read_thing(11);
                        app.Va_rms = read_thing(12);
                        app.Vb_rms = read_thing(13);
                        app.Vc_rms = read_thing(14);
                        app.Vdc = read_thing(15);
                        app.SoC_ev= read_thing(16);
                        app.SoC_bat= read_thing(17);
                        
                        app.Pev = app.Iev*app.Vev;
                        app.Pbat = app.Ibat*app.Vbat;
                        app.PPV = app.IPV*app.VPV;
                        app.PSC = app.ISC*app.VSC;
                        app.PGRID = (app.Ia_rms * app.Va_rms) + (app.Ib_rms * app.Vb_rms) + (app.Ic_rms * app.Vc_rms);
                        
                        app.PPV_val.Text = append(num2str(app.PPV)," W");
                        app.PSC_val.Text = append(num2str(app.PSC)," W");
                        app.PEV_val.Text = append(num2str(app.Pev)," W");
                        app.PBat_val.Text = append(num2str(app.Pbat)," W");
                        app.Pgrid_val.Text = append(num2str(app.PGRID)," W");
                        app.Vdc_val.Text = append(num2str(app.Vdc)," V");
                        app.SoC_bat_val.Text = append(num2str(app.SoC_bat),"%");
                        app.SoC_ev_val.Text = append(num2str(app.SoC_ev),"%");

                        
                    elseif first_string_ascii(1) == 83 && first_string_ascii(9) == opt_text_expected_id      % odd optimization result (first part of array)
                        D = uint8(char(split_string(25)));
                        F=D(1:9)>0;
                        Z = single(str2num(char(D(F))));
                        
                        
                        opt_read_thing(1:23) = single(str2num(char(split_string(2:24))));
                        opt_read_thing(24) = Z;
                        
                        opt_text_expected_id = first_string_ascii(9) + 1;
                        
                        
                        if first_string_ascii(9) == 97
                            app.PPEV_opt(1:24) = opt_read_thing(1:24);
                        elseif first_string_ascii(9) == 99
                            app.PBAT_opt(1:24) = opt_read_thing(1:24);
                        elseif first_string_ascii(9) == 101
                            app.QEC_opt(1:24)= opt_read_thing(1:24);
                        elseif first_string_ascii(9) == 103
                            app.indoor_temperature_opt(1:24)= opt_read_thing(1:24);
                        elseif first_string_ascii(9) == 105
                            app.state_of_charge_ev_opt(1:24)= opt_read_thing(1:24);
                        elseif first_string_ascii(9) == 107
                            app.state_of_charge_bat_opt(1:24)= opt_read_thing(1:24);
                        elseif first_string_ascii(9) == 109
                            app.PLOAD_opt(1:24)= opt_read_thing(1:24);
                        end
                        
                        
                    elseif first_string_ascii(9) == opt_text_expected_id    % even optimization result (second part of array)
                        
                        D = uint8(char(split_string(1)));
                        F=D(1:8)>0;
                        Z = single(str2num(char(D(F))));
                        
                        D2 = uint8(char(split_string(25)));
                        F2=D2(1:9)>0;
                        Z2 = single(str2num(char(D2(F2))));
                        
                        opt_read_thing(1) = Z;
                        opt_read_thing(2:24) = single(str2num(char(split_string(2:24))));
                        opt_read_thing(25) = Z2;
                        
                        opt_text_expected_id = first_string_ascii(9) + 1;
                        
                        
                        if first_string_ascii(9) == 98
                            app.PPEV_opt(25:49) = opt_read_thing(1:25);
                        elseif first_string_ascii(9) == 100
                            app.PBAT_opt(25:49) = opt_read_thing(1:25);
                        elseif first_string_ascii(9) == 102
                            app.QEC_opt(25:49)= opt_read_thing(1:25);
                        elseif first_string_ascii(9) == 104
                            app.indoor_temperature_opt(25:49)= opt_read_thing(1:25);
                        elseif first_string_ascii(9) == 106
                            app.state_of_charge_ev_opt(25:49)= opt_read_thing(1:25);
                        elseif first_string_ascii(9) == 108
                            app.state_of_charge_bat_opt(25:49)= opt_read_thing(1:25);
                        elseif first_string_ascii(9) == 110
                            app.PLOAD_opt(25:49)= opt_read_thing(1:25);
                            app.PlotOptimizationButton.Enable ="On";
                            
                            
                            cDate = date;
                            
                            filename_O_R = append("Optimization_Results_",cDate,".txt");
%                             if isfile(filename_O_R)
%                                 %do nothing if opt data already exists
%                                 disp("Optimization data already exists today")
%                             else
                                fileID_O_R = fopen(filename_O_R,'w');
                                fprintf(fileID_O_R,'%s\t\t\t|\t%s\t|\t%s\t|\t%s\t|\t%s\t|\t%s|\t%s\t|\t%s\n','Date and Time', ...
                                    'Pev (kW)','Pbat (kW)','SoCev (%)','SoCbat (%)','Pthermal (kW)','Tin (°C)','Ploadshift (kW)');
                                
                                currentDate = datetime('now');
                                for i = 1:49
                                    
                                    fprintf(fileID_O_R,['%s\t|\t%8.4f\t|\t%8.4f\t|\t%8.4f\t|\t%8.4f\t|\t%8.4f\t|\t%8.4f\t|\t%8.4f\t\n'], ...
                                        datestr(currentDate),app.PPEV_opt(i),app.PBAT_opt(i),app.state_of_charge_ev_opt(i),app.state_of_charge_bat_opt(i), ... 
                                        app.QEC_opt(i),app.indoor_temperature_opt(i),app.PLOAD_opt(i));
                                    
                                    currentDate = currentDate + minutes(30);
                                    
                                end
                                
                                fclose(fileID_O_R);
                                
%                             end


                        end
                        
                    end
                end
                
                
                pause(0.1)
            end
            
            
        end

        % Button pushed function: WIFIDisconnectButton
        function WIFIDisconnectButtonPushed(app, event)
            
            app.WIFIDisconnectButton.Enable ="Off";
            app.WIFIConnectButton.Enable ="On";
            app.WIFIConnectionstatusLamp.Color = [1 0 0]; %red
            app.SerialConnectButton.Enable ="On";
            
            stop(app.timerObject);
            
            fclose(app.fileID_RT_VI);
            fclose(app.fileID_RT_P);

            % Serial for debugging only
            delete(app.s);
            delete(app.s2);
            
            % Serial for debugging only
            
            delete(app.client);
            
        end

        % Button pushed function: PlotOptimizationButton
        function PlotOptimizationButtonPushed(app, event)
            
            t=0:0.5:24;
            
            if app.EV_flag=="On"
                
                app.PPEV_opt = movmean(app.PPEV_opt,3)
                app.state_of_charge_ev_opt = movmean(app.state_of_charge_ev_opt,3)
                app.PBAT_opt = movmean(app.PBAT_opt,3)
                app.state_of_charge_bat_opt = movmean(app.state_of_charge_bat_opt,3)
                
                
                figure
                xBox = [0, 24, 24, 0];
                yBox = [0, 0, app.Pmax_ev, app.Pmax_ev];
                patch(xBox, yBox, 'black', 'FaceColor', 'green', 'FaceAlpha', 0.1);
                xBox = [0 24 24 0];
                yBox = [0 0 app.Pmin_ev app.Pmin_ev];
                patch(xBox, yBox, 'black', 'FaceColor', 'red', 'FaceAlpha', 0.1);
                hold on;
                plot(t,app.PPEV_opt,'b','LineWidth', 1.5);
                axis([0 24 app.Pmin_ev app.Pmax_ev])
                title('Power Exchange of EV battery')
                xlabel('t (hours)')
                ylabel('P_E_V (kW)')
%                 set(gca, 'Box', 'off', 'TickDir', 'out', 'TickLength', [.02 .02], ...
%                     'XMinorTick', 'off', 'YMinorTick', 'on', 'YGrid', 'on', ...
%                     'XColor', [.3 .3 .3], 'YColor', [.3 .3 .3], 'YTick', app.Pmin_ev:2.5:app.Pmax_ev,'LineWidth', 1)
                set(gca, 'FontName', 'Helvetica')
                
                xticks(0:4:24)
                xticklabels({'00:00','04:00','08:00','12:00','16:00','20:00','24:00'})
                
                figure
                plot(t,app.state_of_charge_ev_opt,'m','LineWidth', 1.5)
                hold on;
                limitline_top = app.SoCmax_ev*ones(1,length(app.state_of_charge_ev_opt));
                plot(t,limitline_top,'r--');
                limitline_bottom = app.SoCmin_ev*ones(1,length(app.state_of_charge_ev_opt));
                plot(t,limitline_bottom,'r--');
                hold off;
                axis([0 24 0 app.Ev_bat_capacity])
                xlabel('t (hours)')
                ylabel('E (kWh)')
                title('Energy of EV Battery')
%                 set(gca, 'Box', 'off', 'TickDir', 'out', 'TickLength', [.02 .02], ...
%                     'XMinorTick', 'off', 'YMinorTick', 'on', 'YGrid', 'on', ...
%                     'XColor', [.3 .3 .3], 'YColor', [.3 .3 .3], 'YTick', 0:10:app.Ev_bat_capacity,'LineWidth', 1)
                xticks(0:4:24)
                xticklabels({'00:00','04:00','08:00','12:00','16:00','20:00','24:00'})
                
            end
            
            
            if app.Battery_flag=="On"
                
                figure
                xBox = [0 0 24 24];
                yBox = [0 app.Pmax_bat app.Pmax_bat 0];
                patch(xBox, yBox, 'black', 'FaceColor', 'green', 'FaceAlpha', 0.1);
                xBox = [0 24 24 0];
                yBox = [0 0 app.Pmin_bat app.Pmin_bat];
                patch(xBox, yBox, 'black', 'FaceColor', 'red', 'FaceAlpha', 0.1);
                hold on;
                plot(t,app.PBAT_opt,'b','LineWidth', 1.5);
                axis([0 24 app.Pmin_bat app.Pmax_bat])
                title('Power Exchange of battery')
                xlabel('t (hours)')
                ylabel('P_B_A_T (kW)')
%                 set(gca, 'Box', 'off', 'TickDir', 'out', 'TickLength', [.02 .02], ...
%                     'XMinorTick', 'off', 'YMinorTick', 'on', 'YGrid', 'on', ...
%                     'XColor', [.3 .3 .3], 'YColor', [.3 .3 .3], 'YTick', app.Pmin_bat:2.5:app.Pmax_bat,'LineWidth', 1)
                set(gca, 'FontName', 'Helvetica')
                xticks(0:4:24)
                xticklabels({'00:00','04:00','08:00','12:00','16:00','20:00','24:00'})
                
                figure
                plot(t,app.state_of_charge_bat_opt,'m','LineWidth', 1.5)
                hold on;
                limitline_top = app.SoCmax_bat*ones(1,length(app.state_of_charge_bat_opt));
                plot(t,limitline_top,'r--');
                limitline_bottom = app.SoCmin_bat*ones(1,length(app.state_of_charge_bat_opt));
                plot(t,limitline_bottom,'r--');
                hold off;
                axis([0 24 0 app.bat_capacity])
                xlabel('t (hours)')
                ylabel('E (kWh)')
                title('Energy of Battery')
%                 set(gca, 'Box', 'off', 'TickDir', 'out', 'TickLength', [.02 .02], ...
%                     'XMinorTick', 'off', 'YMinorTick', 'on', 'YGrid', 'on', ...
%                     'XColor', [.3 .3 .3], 'YColor', [.3 .3 .3], 'YTick', 0:2.5:app.bat_capacity,'LineWidth', 1)
                xticks(0:4:24)
                xticklabels({'00:00','04:00','08:00','12:00','16:00','20:00','24:00'})
                
            end
            %
            %             if app.HeatSystem_flag=="On"
            %
            %                 figure
            %                 plot(t,QEC_electric,'c','LineWidth', 1.5)
            %                 xlabel('t (hours)')
            %                 ylabel('P_Q_E_C (kW)')
            %                 title('Electric Chiller Power')
            %                 axis([0 24 app.QEC_min app.QEC_max])
            %                 set(gca, 'Box', 'off', 'TickDir', 'out', 'TickLength', [.02 .02], ...
            %                     'XMinorTick', 'off', 'YMinorTick', 'off', 'YGrid', 'on', ...
            %                     'XColor', [.3 .3 .3], 'YColor', [.3 .3 .3], 'YTick', app.QEC_min:1:app.QEC_max,'LineWidth', 1)
            %                 xticks(0:4:24)
            %                 xticklabels({'00:00','04:00','08:00','12:00','16:00','20:00','24:00'})
            %
            %                 figure
            %                 plot(t,app.outdoor_temperature,'r','LineWidth', 1.5)
            %                 hold on;
            %                 plot(t,indoor_temperature,'b','LineWidth', 1.5)
            %                 limitline_top = app.Tmax*ones(1,length(t));
            %                 plot(t,limitline_top,'g--');
            %                 limitline_bottom = app.Tmin*ones(1,length(t));
            %                 plot(t,limitline_bottom,'g--');
            %                 xlabel('t (hours)')
            %                 ylabel('Temperature (°C)')
            %                 title('Indoor and outdoor temperature')
            %                 axis([0 24 0 45])
            %                 set(gca, 'Box', 'off', 'TickDir', 'out', 'TickLength', [.02 .02], ...
            %                     'XMinorTick', 'off', 'YMinorTick', 'off', 'YGrid', 'on', ...
            %                     'XColor', [.3 .3 .3], 'YColor', [.3 .3 .3], 'YTick', 0:5:45,'LineWidth', 1)
            %                 legend('Outdoor temperature','Indoor temperature');
            %                 xticks(0:4:24)
            %                 xticklabels({'00:00','04:00','08:00','12:00','16:00','20:00','24:00'})
            %
            %             end
            %
            %
            %             if app.Loadshift_flag=="On"
            %
            %                 figure
            %                 plot(t,app.home_load,'r','LineWidth', 1.5)
            %                 hold on;
            %                 plot(t,PLOAD,'b','LineWidth', 1.5);
            %                 hold off;
            %                 axis([0 24 0 5])
            %                 legend('Initial Non-Thermal Load','Shifted Load')
            %                 title('Load shifting of non-thermal home loads')
            %                 set(gca, 'Box', 'off', 'TickDir', 'out', 'TickLength', [.02 .02], ...
            %                     'XMinorTick', 'off', 'YMinorTick', 'on', 'YGrid', 'on', ...
            %                     'XColor', [.3 .3 .3], 'YColor', [.3 .3 .3], 'YTick', 0:1:5,'LineWidth', 1)
            %                 xticks(0:4:24)
            %                 xticklabels({'00:00','04:00','08:00','12:00','16:00','20:00','24:00'})
            %                 xlabel('t (hours)')
            %                 ylabel('P_L_O_A_D (kW)')
            %
            %                 figure
            %                 plot(t,app.home_load,'r','LineWidth', 1.5)
            %                 hold on;
            %                 plot(t,QEC_electric,'c','LineWidth', 1.5)
            %                 plot(t,QEC_electric+app.home_load,'m','LineWidth', 1.5)
            %                 axis([0 24 0 5])
            %                 legend('Non-Thermal Load','Cooling Load','Final Load')
            %                 title('Home Loads')
            %                 set(gca, 'Box', 'off', 'TickDir', 'out', 'TickLength', [.02 .02], ...
            %                     'XMinorTick', 'off', 'YMinorTick', 'on', 'YGrid', 'on', ...
            %                     'XColor', [.3 .3 .3], 'YColor', [.3 .3 .3], 'YTick', 0:1:5,'LineWidth', 1)
            %                 xticks(0:4:24)
            %                 xticklabels({'00:00','04:00','08:00','12:00','16:00','20:00','24:00'})
            %                 xlabel('t (hours)')
            %                 ylabel('P_L_O_A_D (kW)')
            %
            %             end
            %
            %             if app.solar_panels_state=="On"
            %
            %                 figure
            %                 plot (t,app.PV_Production,'y','LineWidth', 1.5)
            %                 axis([0 24 min(app.PV_Production) max(app.PV_Production)])
            %                 title('Solar Panel Power')
            %                 set(gca, 'Box', 'off', 'TickDir', 'out', 'TickLength', [.02 .02], ...
            %                     'XMinorTick', 'off', 'YMinorTick', 'on', 'YGrid', 'on', ...
            %                     'XColor', [.3 .3 .3], 'YColor', [.3 .3 .3], 'YTick', min(app.PV_Production):2:max(app.PV_Production),'LineWidth', 1)
            %                 xticks(0:4:24)
            %                 xticklabels({'00:00','04:00','08:00','12:00','16:00','20:00','24:00'})
            %                 xlabel('t (hours)')
            %                 ylabel('P_P_V (kW)')
            %
            %             end
            
            %             figure
            %             plot (t,P_Grid,'b','LineWidth', 1.5)
            %             axis([0 24 app.P_grid_min app.P_grid_max])
            %             title('Grid Power Exchange')
            %             set(gca, 'Box', 'off', 'TickDir', 'out', 'TickLength', [.02 .02], ...
            %                 'XMinorTick', 'off', 'YMinorTick', 'on', 'YGrid', 'on', ...
            %                 'XColor', [.3 .3 .3], 'YColor', [.3 .3 .3], 'YTick', -10:5:10,'LineWidth', 1)
            %             xticks(0:4:24)
            %             xticklabels({'00:00','04:00','08:00','12:00','16:00','20:00','24:00'})
            %             hold on;
            %             xBox3 = [0 0 24 24];
            %             yBox3 = [0 app.P_grid_max app.P_grid_max 0];
            %             patch(xBox3, yBox3, 'black', 'FaceColor', 'red', 'FaceAlpha', 0.1);
            %             xBox4 = [0 24 24 0];
            %             yBox4 = [0 0 app.P_grid_min app.P_grid_min];
            %             patch(xBox4, yBox4, 'black', 'FaceColor', 'green', 'FaceAlpha', 0.1);
            %             text(10.5,5,'Buy from the grid');
            %             text(10.5,-5,'Sell to the grid');
            %             hold off;
            %             xlabel('t (hours)')
            %             ylabel('P_G_R_I_D (kW)')
            
            %
            %             figure
            %             plot(t,app.CostOfPower,'r','LineWidth', 1.5)
            %             set(gca, 'Box', 'off', 'TickDir', 'out', 'TickLength', [.02 .02], ...
            %                 'XMinorTick', 'off', 'YMinorTick', 'on', 'YGrid', 'on', ...
            %                 'XColor', [.3 .3 .3], 'YTick', min(app.CostOfPower):0.01:max(app.CostOfPower),'LineWidth', 1)
            %             set(gca, 'FontName', 'Helvetica')
            %             hold on;
            %             axis([0 24 0.07 0.14])
            %             ylabel('Cost (€ / kWh)')
            %             set(gca, 'Box', 'off', 'TickDir', 'out', 'TickLength', [.02 .02], ...
            %                 'XMinorTick', 'off', 'YMinorTick', 'on', 'YGrid', 'on', ...
            %                 'XColor', [.3 .3 .3], 'YColor', [.3 .3 .3], 'YTick', 0.07:0.01:0.14,'LineWidth', 1)
            %             title('Cost of Power')
            %             xlabel('t (hours)')
            %             xticks(0:4:24)
            %             xticklabels({'00:00','04:00','08:00','12:00','16:00','20:00','24:00'})
            %
            %             cost_temp=app.CostOfPower;
            %             outdoor_temperature_temp = app.outdoor_temperature;
            %             home_load_temp = app.home_load;
            %             PV_Production_temp = app.PV_Production;
            %             save('kanellos_R4','t','PPEV','state_of_charge_ev','PBAT','state_of_charge_bat','cost_temp','QEC_electric','outdoor_temperature_temp','indoor_temperature','home_load_temp','PLOAD','PV_Production_temp','cost_temp','final_cost')
            
        end

        % Button pushed function: RunOptimizationButton
        function RunOptimizationButtonPushed(app, event)
            run_optimization_wifi(app)
        end

        % Value changed function: RatedPowerkWEditField
        function RatedPowerkWEditFieldValueChanged(app, event)
            app.solar_panel_power = app.RatedPowerkWEditField.Value;
            
        end

        % Value changed function: SolarPanelsSwitch
        function SolarPanelsSwitchValueChanged(app, event)
            app.solar_panels_state = app.SolarPanelsSwitch.Value;
            if app.SolarPanelsSwitch.Value=="Off"
                app.RatedPowerkWEditField.Enable="Off";
                app.SellingcostofPVPowerkWhEditField.Enable="Off";
            end
            if app.SolarPanelsSwitch.Value=="On"
                app.RatedPowerkWEditField.Enable="On";
                app.SellingcostofPVPowerkWhEditField.Enable="On";
            end
        end

        % Value changed function: P_maxkWEditField
        function P_maxkWEditFieldValueChanged(app, event)
            app.Pmax_ev = app.P_maxkWEditField.Value;
            
        end

        % Value changed function: P_minkWEditField
        function P_minkWEditFieldValueChanged(app, event)
            app.Pmin_ev = app.P_minkWEditField.Value;
            
        end

        % Value changed function: SoC_initialkWhEditField
        function SoC_initialkWhEditFieldValueChanged(app, event)
            app.SoC_initial_ev = app.SoC_initialkWhEditField.Value;
            
        end

        % Value changed function: ConverterEfficiencyEditField
        function ConverterEfficiencyEditFieldValueChanged(app, event)
            app.EV_bat_n = app.ConverterEfficiencyEditField.Value;
            
        end

        % Value changed function: SoC_targetkWhEditField
        function SoC_targetkWhEditFieldValueChanged(app, event)
            app.SoC_target_ev = app.SoC_targetkWhEditField.Value;
            
        end

        % Value changed function: SoC_commutekWhEditField
        function SoC_commutekWhEditFieldValueChanged(app, event)
            app.SoC_commute = str2double(app.SoC_commutekWhEditField.Value);
            
        end

        % Value changed function: P_maxkWEditField_2
        function P_maxkWEditField_2ValueChanged(app, event)
            app.Pmax_bat = app.P_maxkWEditField_2.Value;
            
        end

        % Value changed function: P_minkWEditField_2
        function P_minkWEditField_2ValueChanged(app, event)
            app.Pmin_bat = app.P_minkWEditField_2.Value;
            
        end

        % Value changed function: SoC_initialkWhEditField_2
        function SoC_initialkWhEditField_2ValueChanged(app, event)
            app.SoC_initial_bat = app.SoC_initialkWhEditField_2.Value;
            
        end

        % Value changed function: SoC_targetkWhEditField_2
        function SoC_targetkWhEditField_2ValueChanged(app, event)
            app.SoC_target_bat = app.SoC_targetkWhEditField_2.Value;
            
        end

        % Value changed function: ConverterEfficiencyEditField_2
        function ConverterEfficiencyEditField_2ValueChanged(app, event)
            app.bat_n = app.ConverterEfficiencyEditField_2.Value;
            
        end

        % Callback function
        function SellingcostofPVPowerkWhEditFieldValueChanged(app, event)
            app.CostOfPV = app.SellingcostofPVPowerkWhEditField.Value;
            
        end

        % Value changed function: LoadShiftingEditField
        function LoadShiftingEditFieldValueChanged(app, event)
            app.Load_shifting = app.LoadShiftingEditField.Value;
            
        end

        % Callback function
        function TimestepdthoursEditFieldValueChanged(app, event)
            app.dt = app.TimestepdthoursEditField.Value;
            
        end

        % Value changed function: P_grid_maxkWEditField
        function P_grid_maxkWEditFieldValueChanged(app, event)
            app.P_grid_max = app.P_grid_maxkWEditField.Value;
            
        end

        % Value changed function: P_grid_minkWEditField
        function P_grid_minkWEditFieldValueChanged(app, event)
            app.P_grid_min = app.P_grid_minkWEditField.Value;
            
        end

        % Value changed function: AirDensitykgm3EditField
        function AirDensitykgm3EditFieldValueChanged(app, event)
            app.V = app.AirDensitykgm3EditField.Value;
            
        end

        % Value changed function: HeatCapacityCkJkgCEditField
        function HeatCapacityCkJkgCEditFieldValueChanged(app, event)
            app.C = app.HeatCapacityCkJkgCEditField.Value;
            
        end

        % Value changed function: AirVolumeVm3EditField
        function AirVolumeVm3EditFieldValueChanged(app, event)
            app.rho = app.AirVolumeVm3EditField.Value;
            
        end

        % Value changed function: awEditField
        function awEditFieldValueChanged(app, event)
            app.aw = app.awEditField.Value;
            
        end

        % Value changed function: R_sejm2CWEditField
        function R_sejm2CWEditFieldValueChanged(app, event)
            app.Rsej = app.R_sejm2CWEditField.Value;
            
        end

        % Value changed function: SCEditField
        function SCEditFieldValueChanged(app, event)
            app.SC = app.SCEditField.Value;
            
        end

        % Value changed function: U_wallkWm2CEditField
        function U_wallkWm2CEditFieldValueChanged(app, event)
            app.Uwall = app.U_wallkWm2CEditField.Value;
            
        end

        % Value changed function: U_windowkWm2CEditField
        function U_windowkWm2CEditFieldValueChanged(app, event)
            app.Uwindow = app.U_windowkWm2CEditField.Value;
            
        end

        % Value changed function: WallSurfacem2EditField
        function WallSurfacem2EditFieldValueChanged(app, event)
            app.Fwall = app.WallSurfacem2EditField.Value;
            
        end

        % Value changed function: WindowSurfacem2EditField
        function WindowSurfacem2EditFieldValueChanged(app, event)
            app.Fwindow = app.WindowSurfacem2EditField.Value;
            
        end

        % Value changed function: windowEditField
        function windowEditFieldValueChanged(app, event)
            app.taf_window = app.windowEditField.Value;
            
        end

        % Value changed function: InitialIndoorTemperatureCEditField
        function InitialIndoorTemperatureCEditFieldValueChanged(app, event)
            app.T_in_initial = app.InitialIndoorTemperatureCEditField.Value;
            
        end

        % Value changed function: MaxIndoorTemperatureCEditField
        function MaxIndoorTemperatureCEditFieldValueChanged(app, event)
            app.Tmax = app.MaxIndoorTemperatureCEditField.Value;
            
        end

        % Value changed function: MinIndoorTemperatureCEditField
        function MinIndoorTemperatureCEditFieldValueChanged(app, event)
            app.Tmin = app.MinIndoorTemperatureCEditField.Value;
            
        end

        % Value changed function: MaxHeatingCoolingPowerkWEditField
        function MaxHeatingCoolingPowerkWEditFieldValueChanged(app, event)
            app.QEC_max = app.MaxHeatingCoolingPowerkWEditField.Value;
            
        end

        % Value changed function: MinHeatingCoolingPowerkWEditField
        function MinHeatingCoolingPowerkWEditFieldValueChanged(app, event)
            app.QEC_min = app.MinHeatingCoolingPowerkWEditField.Value;
            
        end

        % Value changed function: COPEditField
        function COPEditFieldValueChanged(app, event)
            app.COP = app.COPEditField.Value;
            
        end

        % Value changed function: PSOParticlesEditField
        function PSOParticlesEditFieldValueChanged(app, event)
            app.ps =app.PSOParticlesEditField.Value;
            
        end

        % Value changed function: PSOEpochsEditField
        function PSOEpochsEditFieldValueChanged(app, event)
            app.epoch = app.PSOEpochsEditField.Value;
            
        end

        % Callback function
        function OptimisationstartDropDownValueChanged(app, event)
            app.OptimisationStartTime = str2double(app.OptimisationstartDropDown.Value);
            
        end

        % Callback function
        function EVBatterydtDropDownValueChanged(app, event)
            app.dim1 = str2double(app.EVBatterydtDropDown.Value);
            
        end

        % Callback function
        function ThermaldtDropDownValueChanged(app, event)
            app.dim2 = str2double(app.ThermaldtDropDown.Value);
            
        end

        % Callback function
        function LoadshiftingdtDropDownValueChanged(app, event)
            app.dim4 = str2double(app.LoadshiftingdtDropDown.Value);
            
        end

        % Callback function
        function RegularBatterydtDropDownValueChanged(app, event)
            app.dim3 = str2double(app.RegularBatterydtDropDown.Value);
        end

        % Value changed function: 
        % EVDeparturehoursinceoptimisationstartSpinner
        function EVDeparturehoursinceoptimisationstartSpinnerValueChanged(app, event)
            app.t_leave = 2*app.EVDeparturehoursinceoptimisationstartSpinner.Value+1;
            if (app.EVDeparturehoursinceoptimisationstartSpinner.Value)>(app.EVReturnhoursinceoptimisationstartSpinner.Value)
                app.EVReturnhoursinceoptimisationstartSpinner.Value = app.EVDeparturehoursinceoptimisationstartSpinner.Value;
            end
        end

        % Value changed function: 
        % EVReturnhoursinceoptimisationstartSpinner
        function EVReturnhoursinceoptimisationstartSpinnerValueChanged(app, event)
            app.t_return = 2*app.EVReturnhoursinceoptimisationstartSpinner.Value+1;
            
        end

        % Value changed function: ElectricVehicleSwitch
        function ElectricVehicleSwitchValueChanged(app, event)
            app.EV_flag = app.ElectricVehicleSwitch.Value;
            if app.ElectricVehicleSwitch.Value=="Off"
                app.P_maxkWEditField.Enable="Off";
                app.P_minkWEditField.Enable="Off";
                app.EVBatteryCapacitykWhEditField.Enable="Off";
                app.SoC_initialkWhEditField.Enable="Off";
                app.SoC_targetkWhEditField.Enable="Off";
                app.ConverterEfficiencyEditField.Enable="Off";
                app.SoC_commutekWhEditField.Enable="Off";
                app.EVReturnhoursinceoptimisationstartSpinner.Enable="Off";
                app.EVDeparturehoursinceoptimisationstartSpinner.Enable="Off";
            end
            if app.ElectricVehicleSwitch.Value=="On"
                app.P_maxkWEditField.Enable="On";
                app.P_minkWEditField.Enable="On";
                app.EVBatteryCapacitykWhEditField.Enable="On";
                app.SoC_initialkWhEditField.Enable="On";
                app.SoC_targetkWhEditField.Enable="On";
                app.ConverterEfficiencyEditField.Enable="On";
                app.SoC_commutekWhEditField.Enable="On";
                app.EVReturnhoursinceoptimisationstartSpinner.Enable="On";
                app.EVDeparturehoursinceoptimisationstartSpinner.Enable="On";
            end
        end

        % Value changed function: BatterySwitch
        function BatterySwitchValueChanged(app, event)
            app.Battery_flag = app.BatterySwitch.Value;
            if app.BatterySwitch.Value == "Off"
                app.P_maxkWEditField_2.Enable="Off";
                app.P_minkWEditField_2.Enable="Off";
                app.CapacitykWhEditField.Enable="Off";
                app.SoC_initialkWhEditField_2.Enable="Off";
                app.SoC_targetkWhEditField_2.Enable="Off";
                app.ConverterEfficiencyEditField_2.Enable="Off";
            end
            if app.BatterySwitch.Value == "On"
                app.P_maxkWEditField_2.Enable="On";
                app.P_minkWEditField_2.Enable="On";
                app.CapacitykWhEditField.Enable="On";
                app.SoC_initialkWhEditField_2.Enable="On";
                app.SoC_targetkWhEditField_2.Enable="On";
                app.ConverterEfficiencyEditField_2.Enable="On";
            end
            
        end

        % Value changed function: ShiftingSwitch
        function ShiftingSwitchValueChanged(app, event)
            app.Loadshift_flag = app.ShiftingSwitch.Value;
            if app.ShiftingSwitch.Value == "Off"
                app.LoadShiftingEditField.Enable="Off";
            end
            if app.ShiftingSwitch.Value == "On"
                app.LoadShiftingEditField.Enable="On";
            end
        end

        % Value changed function: HeatingSystemSwitch
        function HeatingSystemSwitchValueChanged(app, event)
            app.HeatSystem_flag = app.HeatingSystemSwitch.Value;
            if app.HeatingSystemSwitch.Value=="Off"
                app.AirDensitykgm3EditField.Enable="Off";
                app.HeatCapacityCkJkgCEditField.Enable="Off";
                app.AirVolumeVm3EditField.Enable="Off";
                app.awEditField.Enable="Off";
                app.R_sejm2CWEditField.Enable="Off";
                app.U_wallkWm2CEditField.Enable="Off";
                app.U_windowkWm2CEditField.Enable="Off";
                app.WallSurfacem2EditField.Enable="Off";
                app.WindowSurfacem2EditField.Enable="Off";
                app.windowEditField.Enable="Off";
                app.SCEditField.Enable="Off";
                app.InitialIndoorTemperatureCEditField.Enable="Off";
                app.MaxIndoorTemperatureCEditField.Enable="Off";
                app.MinIndoorTemperatureCEditField.Enable="Off";
                app.MaxHeatingCoolingPowerkWEditField.Enable="Off";
                app.MinHeatingCoolingPowerkWEditField.Enable="Off";
                app.COPEditField.Enable="Off";
            end
            if app.HeatingSystemSwitch.Value=="On"
                app.AirDensitykgm3EditField.Enable="On";
                app.HeatCapacityCkJkgCEditField.Enable="On";
                app.AirVolumeVm3EditField.Enable="On";
                app.awEditField.Enable="On";
                app.R_sejm2CWEditField.Enable="On";
                app.U_wallkWm2CEditField.Enable="On";
                app.U_windowkWm2CEditField.Enable="On";
                app.WallSurfacem2EditField.Enable="On";
                app.WindowSurfacem2EditField.Enable="On";
                app.windowEditField.Enable="On";
                app.SCEditField.Enable="On";
                app.InitialIndoorTemperatureCEditField.Enable="On";
                app.MaxIndoorTemperatureCEditField.Enable="On";
                app.MinIndoorTemperatureCEditField.Enable="On";
                app.MaxHeatingCoolingPowerkWEditField.Enable="On";
                app.MinHeatingCoolingPowerkWEditField.Enable="On";
                app.COPEditField.Enable="On";
            end
        end

        % Value changed function: CapacitykWhEditField
        function CapacitykWhEditFieldValueChanged(app, event)
            app.bat_capacity = app.CapacitykWhEditField.Value;
            app.SoCmax_bat = app.Ev_bat_capacity*0.9;
            app.SoCmin_bat = app.Ev_bat_capacity*0.1;
        end

        % Value changed function: EVBatteryCapacitykWhEditField
        function EVBatteryCapacitykWhEditFieldValueChanged(app, event)
            app.Ev_bat_capacity = app.EVBatteryCapacitykWhEditField.Value;
            app.SoCmax_ev = app.Ev_bat_capacity*0.9;
            app.SoCmin_ev = app.Ev_bat_capacity*0.1;
        end

        % Value changed function: AdvancedSettingsCheckBox
        function AdvancedSettingsCheckBoxValueChanged(app, event)
            if app.AdvancedSettingsCheckBox.Value==1
                app.AdvancedSettingsPanel.Visible="On";
                app.ThermodynamicModelPanel.Visible="Off";
            else
                app.AdvancedSettingsPanel.Visible="Off";
                app.ThermodynamicModelPanel.Visible="On";
            end
        end

        % Value changed function: CommuteSwitch
        function CommuteSwitchValueChanged(app, event)
            app.commute_enable = app.CommuteSwitch.Value;
            if app.CommuteSwitch.Value == "Off"
                app.SoC_commutekWhEditField.Enable="Off";
                app.EVReturnhoursinceoptimisationstartSpinner.Enable="Off";
                app.EVDeparturehoursinceoptimisationstartSpinner.Enable="Off";
            elseif app.CommuteSwitch.Value == "On"
                app.SoC_commutekWhEditField.Enable="On";
                app.EVReturnhoursinceoptimisationstartSpinner.Enable="On";
                app.EVDeparturehoursinceoptimisationstartSpinner.Enable="On";
            end
        end

        % Value changed function: COMEditField
        function COMEditFieldValueChanged(app, event)
            app.com = app.COMEditField.Value;
            
        end

        % Value changed function: BaudDropDown
        function BaudDropDownValueChanged(app, event)
            app.baudrate = str2double(app.BaudDropDown.Value);
            
        end

        % Value changed function: IPEditField
        function IPEditFieldValueChanged(app, event)
            app.ip = app.IPEditField.Value;
            
        end

        % Value changed function: PortEditField
        function PortEditFieldValueChanged(app, event)
            app.port = app.PortEditField.Value;
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            winopen('USER_MANUAL.pdf')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OptimizationGUIUIFigure = uifigure('Visible', 'off');
            app.OptimizationGUIUIFigure.Name = 'Optimization GUI';
            app.ElectricVehicleBatteryPanel = uipanel(app.OptimizationGUIUIFigure);
            app.ElectricVehicleBatteryPanel.Title = 'Electric Vehicle Battery';
            app.P_maxkWEditFieldLabel = uilabel(app.ElectricVehicleBatteryPanel);
            app.P_maxkWEditFieldLabel.Text = 'P_max (kW)';
            app.P_maxkWEditField = uieditfield(app.ElectricVehicleBatteryPanel, 'numeric');
            app.P_maxkWEditField.ValueChangedFcn = createCallbackFcn(app, @P_maxkWEditFieldValueChanged, true);
            app.P_maxkWEditField.Value = 0.06;
            app.P_minkWEditFieldLabel = uilabel(app.ElectricVehicleBatteryPanel);
            app.P_minkWEditFieldLabel.Text = 'P_min (kW)';
            app.P_minkWEditField = uieditfield(app.ElectricVehicleBatteryPanel, 'numeric');
            app.P_minkWEditField.ValueChangedFcn = createCallbackFcn(app, @P_minkWEditFieldValueChanged, true);
            app.P_minkWEditField.Value = -0.06;
            app.EVBatteryCapacitykWhEditFieldLabel = uilabel(app.ElectricVehicleBatteryPanel);
            app.EVBatteryCapacitykWhEditFieldLabel.Text = 'EV Battery Capacity (kWh)';
            app.EVBatteryCapacitykWhEditField = uieditfield(app.ElectricVehicleBatteryPanel, 'numeric');
            app.EVBatteryCapacitykWhEditField.ValueChangedFcn = createCallbackFcn(app, @EVBatteryCapacitykWhEditFieldValueChanged, true);
            app.EVBatteryCapacitykWhEditField.Value = 0.286;
            app.SoC_initialkWhEditFieldLabel = uilabel(app.ElectricVehicleBatteryPanel);
            app.SoC_initialkWhEditFieldLabel.Text = 'SoC_initial (kWh)';
            app.SoC_initialkWhEditField = uieditfield(app.ElectricVehicleBatteryPanel, 'numeric');
            app.SoC_initialkWhEditField.ValueChangedFcn = createCallbackFcn(app, @SoC_initialkWhEditFieldValueChanged, true);
            app.SoC_initialkWhEditField.Value = 0.2;
            app.ConverterEfficiencyEditFieldLabel = uilabel(app.ElectricVehicleBatteryPanel);
            app.ConverterEfficiencyEditFieldLabel.Text = 'Converter Efficiency';
            app.ConverterEfficiencyEditField = uieditfield(app.ElectricVehicleBatteryPanel, 'numeric');
            app.ConverterEfficiencyEditField.ValueChangedFcn = createCallbackFcn(app, @ConverterEfficiencyEditFieldValueChanged, true);
            app.ConverterEfficiencyEditField.Value = 0.9;
            app.CommutesettingsPanel = uipanel(app.ElectricVehicleBatteryPanel);
            app.CommutesettingsPanel.Title = 'Commute settings';
            app.SoC_commutekWhEditFieldLabel = uilabel(app.CommutesettingsPanel);
            app.SoC_commutekWhEditFieldLabel.Text = 'SoC_commute (kWh)';
            app.SoC_commutekWhEditField = uieditfield(app.CommutesettingsPanel, 'text');
            app.SoC_commutekWhEditField.ValueChangedFcn = createCallbackFcn(app, @SoC_commutekWhEditFieldValueChanged, true);
            app.SoC_commutekWhEditField.Tooltip = {''};
            app.SoC_commutekWhEditField.Value = '3';
            app.EVDeparturehoursinceoptimisationstartSpinnerLabel = uilabel(app.CommutesettingsPanel);
            app.EVDeparturehoursinceoptimisationstartSpinnerLabel.Text = {'EV Departure hour '; '(since optimisation start)'};
            app.EVDeparturehoursinceoptimisationstartSpinner = uispinner(app.CommutesettingsPanel);
            app.EVDeparturehoursinceoptimisationstartSpinner.Step = 0.5;
            app.EVDeparturehoursinceoptimisationstartSpinner.Limits = [0 23.5];
            app.EVDeparturehoursinceoptimisationstartSpinner.ValueChangedFcn = createCallbackFcn(app, @EVDeparturehoursinceoptimisationstartSpinnerValueChanged, true);
            app.EVDeparturehoursinceoptimisationstartSpinner.Value = 7;
            app.EVReturnhoursinceoptimisationstartSpinnerLabel = uilabel(app.CommutesettingsPanel);
            app.EVReturnhoursinceoptimisationstartSpinnerLabel.Text = {'EV Return hour '; '(since optimisation start)'};
            app.EVReturnhoursinceoptimisationstartSpinner = uispinner(app.CommutesettingsPanel);
            app.EVReturnhoursinceoptimisationstartSpinner.Step = 0.5;
            app.EVReturnhoursinceoptimisationstartSpinner.Limits = [0 23.5];
            app.EVReturnhoursinceoptimisationstartSpinner.ValueChangedFcn = createCallbackFcn(app, @EVReturnhoursinceoptimisationstartSpinnerValueChanged, true);
            app.EVReturnhoursinceoptimisationstartSpinner.Value = 10.5;
            app.CommuteSwitchLabel = uilabel(app.CommutesettingsPanel);
            app.CommuteSwitchLabel.Text = 'Commute';
            app.CommuteSwitch = uiswitch(app.CommutesettingsPanel, 'slider');
            app.CommuteSwitch.ValueChangedFcn = createCallbackFcn(app, @CommuteSwitchValueChanged, true);
            app.SoC_targetkWhEditFieldLabel = uilabel(app.ElectricVehicleBatteryPanel);
            app.SoC_targetkWhEditFieldLabel.Text = 'SoC_target (kWh)';
            app.SoC_targetkWhEditField = uieditfield(app.ElectricVehicleBatteryPanel, 'numeric');
            app.SoC_targetkWhEditField.ValueChangedFcn = createCallbackFcn(app, @SoC_targetkWhEditFieldValueChanged, true);
            app.SoC_targetkWhEditField.Value = 0.2;
            app.ElectricVehicleSwitchLabel = uilabel(app.ElectricVehicleBatteryPanel);
            app.ElectricVehicleSwitchLabel.Text = 'Electric Vehicle';
            app.ElectricVehicleSwitch = uiswitch(app.ElectricVehicleBatteryPanel, 'slider');
            app.ElectricVehicleSwitch.ValueChangedFcn = createCallbackFcn(app, @ElectricVehicleSwitchValueChanged, true);
            app.ElectricVehicleSwitch.Value = 'On';
            app.RegularBatteryPanel = uipanel(app.OptimizationGUIUIFigure);
            app.RegularBatteryPanel.Title = 'Regular Battery';
            app.P_maxkWEditField_2Label = uilabel(app.RegularBatteryPanel);
            app.P_maxkWEditField_2Label.Text = 'P_max (kW)';
            app.P_maxkWEditField_2 = uieditfield(app.RegularBatteryPanel, 'numeric');
            app.P_maxkWEditField_2.ValueChangedFcn = createCallbackFcn(app, @P_maxkWEditField_2ValueChanged, true);
            app.P_maxkWEditField_2.Value = 0.06;
            app.P_minkWEditField_2Label = uilabel(app.RegularBatteryPanel);
            app.P_minkWEditField_2Label.Text = 'P_min (kW)';
            app.P_minkWEditField_2 = uieditfield(app.RegularBatteryPanel, 'numeric');
            app.P_minkWEditField_2.ValueChangedFcn = createCallbackFcn(app, @P_minkWEditField_2ValueChanged, true);
            app.P_minkWEditField_2.Value = -0.06;
            app.CapacitykWhEditFieldLabel = uilabel(app.RegularBatteryPanel);
            app.CapacitykWhEditFieldLabel.Text = 'Capacity (kWh)';
            app.CapacitykWhEditField = uieditfield(app.RegularBatteryPanel, 'numeric');
            app.CapacitykWhEditField.ValueChangedFcn = createCallbackFcn(app, @CapacitykWhEditFieldValueChanged, true);
            app.CapacitykWhEditField.Value = 0.286;
            app.SoC_initialkWhEditField_2Label = uilabel(app.RegularBatteryPanel);
            app.SoC_initialkWhEditField_2Label.Text = 'SoC_initial (kWh)';
            app.SoC_initialkWhEditField_2 = uieditfield(app.RegularBatteryPanel, 'numeric');
            app.SoC_initialkWhEditField_2.ValueChangedFcn = createCallbackFcn(app, @SoC_initialkWhEditField_2ValueChanged, true);
            app.SoC_initialkWhEditField_2.Value = 0.2;
            app.ConverterEfficiencyEditField_2Label = uilabel(app.RegularBatteryPanel);
            app.ConverterEfficiencyEditField_2Label.Text = 'Converter Efficiency';
            app.ConverterEfficiencyEditField_2 = uieditfield(app.RegularBatteryPanel, 'numeric');
            app.ConverterEfficiencyEditField_2.ValueChangedFcn = createCallbackFcn(app, @ConverterEfficiencyEditField_2ValueChanged, true);
            app.ConverterEfficiencyEditField_2.Value = 0.9;
            app.SoC_targetkWhEditField_2Label = uilabel(app.RegularBatteryPanel);
            app.SoC_targetkWhEditField_2Label.Text = 'SoC_target (kWh)';
            app.SoC_targetkWhEditField_2 = uieditfield(app.RegularBatteryPanel, 'numeric');
            app.SoC_targetkWhEditField_2.ValueChangedFcn = createCallbackFcn(app, @SoC_targetkWhEditField_2ValueChanged, true);
            app.SoC_targetkWhEditField_2.Value = 0.2;
            app.BatterySwitchLabel = uilabel(app.RegularBatteryPanel);
            app.BatterySwitchLabel.Text = 'Battery';
            app.BatterySwitch = uiswitch(app.RegularBatteryPanel, 'slider');
            app.BatterySwitch.ValueChangedFcn = createCallbackFcn(app, @BatterySwitchValueChanged, true);
            app.BatterySwitch.Value = 'On';
            app.SolarPanels = uipanel(app.OptimizationGUIUIFigure);
            app.SolarPanels.Title = 'Solar Panels';
            app.SolarPanelsSwitchLabel = uilabel(app.SolarPanels);
            app.SolarPanelsSwitchLabel.Text = 'Solar Panels';
            app.SolarPanelsSwitch = uiswitch(app.SolarPanels, 'slider');
            app.SolarPanelsSwitch.ValueChangedFcn = createCallbackFcn(app, @SolarPanelsSwitchValueChanged, true);
            app.RatedPowerkWEditFieldLabel = uilabel(app.SolarPanels);
            app.RatedPowerkWEditFieldLabel.Text = 'Rated Power (kW)';
            app.RatedPowerkWEditField = uieditfield(app.SolarPanels, 'numeric');
            app.RatedPowerkWEditField.ValueChangedFcn = createCallbackFcn(app, @RatedPowerkWEditFieldValueChanged, true);
            app.RatedPowerkWEditField.Value = 10.36;
            app.LoadShiftingPanel = uipanel(app.OptimizationGUIUIFigure);
            app.LoadShiftingPanel.Title = 'Load Shifting';
            app.LoadShiftingEditFieldLabel = uilabel(app.LoadShiftingPanel);
            app.LoadShiftingEditFieldLabel.Text = 'Load Shifting (%)';
            app.LoadShiftingEditField = uieditfield(app.LoadShiftingPanel, 'numeric');
            app.LoadShiftingEditField.ValueChangedFcn = createCallbackFcn(app, @LoadShiftingEditFieldValueChanged, true);
            app.LoadShiftingEditField.Value = 25;
            app.ShiftingSwitchLabel = uilabel(app.LoadShiftingPanel);
            app.ShiftingSwitchLabel.Text = 'Shifting';
            app.ShiftingSwitch = uiswitch(app.LoadShiftingPanel, 'slider');
            app.ShiftingSwitch.ValueChangedFcn = createCallbackFcn(app, @ShiftingSwitchValueChanged, true);
            app.GridPanel = uipanel(app.OptimizationGUIUIFigure);
            app.GridPanel.Title = 'Grid';
            app.P_grid_maxkWEditFieldLabel = uilabel(app.GridPanel);
            app.P_grid_maxkWEditFieldLabel.Text = 'P_grid_max (kW)';
            app.P_grid_maxkWEditField = uieditfield(app.GridPanel, 'numeric');
            app.P_grid_maxkWEditField.ValueChangedFcn = createCallbackFcn(app, @P_grid_maxkWEditFieldValueChanged, true);
            app.P_grid_maxkWEditField.Value = 10;
            app.P_grid_minkWEditFieldLabel = uilabel(app.GridPanel);
            app.P_grid_minkWEditFieldLabel.Text = 'P_grid_min (kW)';
            app.P_grid_minkWEditField = uieditfield(app.GridPanel, 'numeric');
            app.P_grid_minkWEditField.ValueChangedFcn = createCallbackFcn(app, @P_grid_minkWEditFieldValueChanged, true);
            app.P_grid_minkWEditField.Value = -10;
            app.ThermodynamicModelPanel = uipanel(app.OptimizationGUIUIFigure);
            app.ThermodynamicModelPanel.Title = 'Thermodynamic Model';
            app.AirDensitykgm3EditFieldLabel = uilabel(app.ThermodynamicModelPanel);
            app.AirDensitykgm3EditFieldLabel.Text = 'Air Density ρ (kg/m^3)';
            app.AirDensitykgm3EditField = uieditfield(app.ThermodynamicModelPanel, 'numeric');
            app.AirDensitykgm3EditField.ValueChangedFcn = createCallbackFcn(app, @AirDensitykgm3EditFieldValueChanged, true);
            app.AirDensitykgm3EditField.Value = 1.2;
            app.HeatCapacityCkJkgCEditFieldLabel = uilabel(app.ThermodynamicModelPanel);
            app.HeatCapacityCkJkgCEditFieldLabel.Text = 'Heat Capacity C (kJ/(kg*°C))';
            app.HeatCapacityCkJkgCEditField = uieditfield(app.ThermodynamicModelPanel, 'numeric');
            app.HeatCapacityCkJkgCEditField.ValueChangedFcn = createCallbackFcn(app, @HeatCapacityCkJkgCEditFieldValueChanged, true);
            app.HeatCapacityCkJkgCEditField.Value = 1;
            app.AirVolumeVm3EditFieldLabel = uilabel(app.ThermodynamicModelPanel);
            app.AirVolumeVm3EditFieldLabel.Text = 'Air Volume V (m^3)';
            app.AirVolumeVm3EditField = uieditfield(app.ThermodynamicModelPanel, 'numeric');
            app.AirVolumeVm3EditField.ValueChangedFcn = createCallbackFcn(app, @AirVolumeVm3EditFieldValueChanged, true);
            app.AirVolumeVm3EditField.Value = 320;
            app.U_wallkWm2CEditFieldLabel = uilabel(app.ThermodynamicModelPanel);
            app.U_wallkWm2CEditFieldLabel.Text = 'U_wall (kW/(m^2*°C))';
            app.U_wallkWm2CEditField = uieditfield(app.ThermodynamicModelPanel, 'numeric');
            app.U_wallkWm2CEditField.ValueChangedFcn = createCallbackFcn(app, @U_wallkWm2CEditFieldValueChanged, true);
            app.U_wallkWm2CEditField.Value = 0.00048;
            app.U_windowkWm2CEditFieldLabel = uilabel(app.ThermodynamicModelPanel);
            app.U_windowkWm2CEditFieldLabel.Text = 'U_window (kW/(m^2*°C))';
            app.U_windowkWm2CEditField = uieditfield(app.ThermodynamicModelPanel, 'numeric');
            app.U_windowkWm2CEditField.ValueChangedFcn = createCallbackFcn(app, @U_windowkWm2CEditFieldValueChanged, true);
            app.U_windowkWm2CEditField.Value = 0.0029;
            app.WallSurfacem2EditFieldLabel = uilabel(app.ThermodynamicModelPanel);
            app.WallSurfacem2EditFieldLabel.Text = 'Wall Surface (m^2)';
            app.WallSurfacem2EditField = uieditfield(app.ThermodynamicModelPanel, 'numeric');
            app.WallSurfacem2EditField.ValueChangedFcn = createCallbackFcn(app, @WallSurfacem2EditFieldValueChanged, true);
            app.WallSurfacem2EditField.Value = 224;
            app.WindowSurfacem2EditFieldLabel = uilabel(app.ThermodynamicModelPanel);
            app.WindowSurfacem2EditFieldLabel.Text = 'Window Surface (m^2)';
            app.WindowSurfacem2EditField = uieditfield(app.ThermodynamicModelPanel, 'numeric');
            app.WindowSurfacem2EditField.ValueChangedFcn = createCallbackFcn(app, @WindowSurfacem2EditFieldValueChanged, true);
            app.WindowSurfacem2EditField.Value = 30;
            app.awEditFieldLabel = uilabel(app.ThermodynamicModelPanel);
            app.awEditFieldLabel.Text = 'aw';
            app.awEditField = uieditfield(app.ThermodynamicModelPanel, 'numeric');
            app.awEditField.ValueChangedFcn = createCallbackFcn(app, @awEditFieldValueChanged, true);
            app.awEditField.Value = 18.63;
            app.R_sejm2CWEditFieldLabel = uilabel(app.ThermodynamicModelPanel);
            app.R_sejm2CWEditFieldLabel.Text = 'R_sej (m^2*°C/W)';
            app.R_sejm2CWEditField = uieditfield(app.ThermodynamicModelPanel, 'numeric');
            app.R_sejm2CWEditField.ValueChangedFcn = createCallbackFcn(app, @R_sejm2CWEditFieldValueChanged, true);
            app.R_sejm2CWEditField.Value = 0.04;
            app.windowEditFieldLabel = uilabel(app.ThermodynamicModelPanel);
            app.windowEditFieldLabel.Text = 'τ_window';
            app.windowEditField = uieditfield(app.ThermodynamicModelPanel, 'numeric');
            app.windowEditField.ValueChangedFcn = createCallbackFcn(app, @windowEditFieldValueChanged, true);
            app.windowEditField.Value = 1.1;
            app.SCEditFieldLabel = uilabel(app.ThermodynamicModelPanel);
            app.SCEditFieldLabel.Text = 'SC';
            app.SCEditField = uieditfield(app.ThermodynamicModelPanel, 'numeric');
            app.SCEditField.ValueChangedFcn = createCallbackFcn(app, @SCEditFieldValueChanged, true);
            app.SCEditField.Value = 0.54;
            app.HeatingCoolingsystemPanel = uipanel(app.ThermodynamicModelPanel);
            app.HeatingCoolingsystemPanel.Title = 'Heating/Cooling system';
            app.InitialIndoorTemperatureCEditFieldLabel = uilabel(app.HeatingCoolingsystemPanel);
            app.InitialIndoorTemperatureCEditFieldLabel.Text = 'Initial Indoor Temperature (°C)';
            app.InitialIndoorTemperatureCEditField = uieditfield(app.HeatingCoolingsystemPanel, 'numeric');
            app.InitialIndoorTemperatureCEditField.ValueChangedFcn = createCallbackFcn(app, @InitialIndoorTemperatureCEditFieldValueChanged, true);
            app.InitialIndoorTemperatureCEditField.Value = 25;
            app.MaxIndoorTemperatureCEditFieldLabel = uilabel(app.HeatingCoolingsystemPanel);
            app.MaxIndoorTemperatureCEditFieldLabel.Text = 'Max Indoor Temperature (°C)';
            app.MaxIndoorTemperatureCEditField = uieditfield(app.HeatingCoolingsystemPanel, 'numeric');
            app.MaxIndoorTemperatureCEditField.ValueChangedFcn = createCallbackFcn(app, @MaxIndoorTemperatureCEditFieldValueChanged, true);
            app.MaxIndoorTemperatureCEditField.Value = 25;
            app.MinIndoorTemperatureCEditFieldLabel = uilabel(app.HeatingCoolingsystemPanel);
            app.MinIndoorTemperatureCEditFieldLabel.Text = 'Min Indoor Temperature (°C)';
            app.MinIndoorTemperatureCEditField = uieditfield(app.HeatingCoolingsystemPanel, 'numeric');
            app.MinIndoorTemperatureCEditField.ValueChangedFcn = createCallbackFcn(app, @MinIndoorTemperatureCEditFieldValueChanged, true);
            app.MinIndoorTemperatureCEditField.Value = 23;
            app.MaxHeatingCoolingPowerkWLabel = uilabel(app.HeatingCoolingsystemPanel);
            app.MaxHeatingCoolingPowerkWLabel.Text = 'Max Heating/Cooling Power (kW)';
            app.MaxHeatingCoolingPowerkWEditField = uieditfield(app.HeatingCoolingsystemPanel, 'numeric');
            app.MaxHeatingCoolingPowerkWEditField.ValueChangedFcn = createCallbackFcn(app, @MaxHeatingCoolingPowerkWEditFieldValueChanged, true);
            app.MaxHeatingCoolingPowerkWEditField.Value = 5;
            app.MinHeatingCoolingPowerkWEditFieldLabel = uilabel(app.HeatingCoolingsystemPanel);
            app.MinHeatingCoolingPowerkWEditFieldLabel.Text = 'Min Heating/Cooling Power (kW)';
            app.MinHeatingCoolingPowerkWEditField = uieditfield(app.HeatingCoolingsystemPanel, 'numeric');
            app.MinHeatingCoolingPowerkWEditField.ValueChangedFcn = createCallbackFcn(app, @MinHeatingCoolingPowerkWEditFieldValueChanged, true);
            app.COPEditFieldLabel = uilabel(app.HeatingCoolingsystemPanel);
            app.COPEditFieldLabel.Text = 'COP';
            app.COPEditField = uieditfield(app.HeatingCoolingsystemPanel, 'numeric');
            app.COPEditField.ValueChangedFcn = createCallbackFcn(app, @COPEditFieldValueChanged, true);
            app.COPEditField.Value = 3.5;
            app.HeatingSystemSwitchLabel = uilabel(app.ThermodynamicModelPanel);
            app.HeatingSystemSwitchLabel.Text = 'Heating System';
            app.HeatingSystemSwitch = uiswitch(app.ThermodynamicModelPanel, 'slider');
            app.HeatingSystemSwitch.ValueChangedFcn = createCallbackFcn(app, @HeatingSystemSwitchValueChanged, true);
            app.AdvancedSettingsCheckBox = uicheckbox(app.OptimizationGUIUIFigure);
            app.AdvancedSettingsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdvancedSettingsCheckBoxValueChanged, true);
            app.AdvancedSettingsCheckBox.Text = '  Advanced Settings';
            app.SerialCommunicationPanel = uipanel(app.OptimizationGUIUIFigure);
            app.SerialCommunicationPanel.Title = 'Serial Communication';
            app.ConnectionstatusLampLabel = uilabel(app.SerialCommunicationPanel);
            app.ConnectionstatusLampLabel.Text = {'Connection'; 'status'};
            app.SerialConnectionstatusLamp = uilamp(app.SerialCommunicationPanel);
            app.SerialConnectButton = uibutton(app.SerialCommunicationPanel, 'push');
            app.SerialConnectButton.ButtonPushedFcn = createCallbackFcn(app, @SerialConnectButtonPushed, true);
            app.SerialConnectButton.Text = 'Connect';
            app.SerialDisconnectButton = uibutton(app.SerialCommunicationPanel, 'push');
            app.SerialDisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @SerialDisconnectButtonPushed, true);
            app.SerialDisconnectButton.Text = 'Disconnect';
            app.COMEditFieldLabel = uilabel(app.SerialCommunicationPanel);
            app.COMEditFieldLabel.Text = 'COM';
            app.COMEditField = uieditfield(app.SerialCommunicationPanel, 'text');
            app.COMEditField.ValueChangedFcn = createCallbackFcn(app, @COMEditFieldValueChanged, true);
            app.COMEditField.Value = '8';
            app.BaudDropDownLabel = uilabel(app.SerialCommunicationPanel);
            app.BaudDropDownLabel.Text = 'Baud';
            app.BaudDropDown = uidropdown(app.SerialCommunicationPanel);
            app.BaudDropDown.Items = {'300', '600', '1200', '1800', '2400', '4800', '7200', '9600', '14400', '19200', '38400', '57600', '115200', '230400', '460800', '921600'};
            app.BaudDropDown.ValueChangedFcn = createCallbackFcn(app, @BaudDropDownValueChanged, true);
            app.BaudDropDown.Value = '115200';
            app.WifiCommunicationPanel = uipanel(app.OptimizationGUIUIFigure);
            app.WifiCommunicationPanel.Title = 'Wifi Communication';
            app.ConnectionstatusLamp_2Label = uilabel(app.WifiCommunicationPanel);
            app.ConnectionstatusLamp_2Label.Text = {'Connection'; 'status'};
            app.WIFIConnectionstatusLamp = uilamp(app.WifiCommunicationPanel);
            app.WIFIConnectButton = uibutton(app.WifiCommunicationPanel, 'push');
            app.WIFIConnectButton.ButtonPushedFcn = createCallbackFcn(app, @WIFIConnectButtonPushed, true);
            app.WIFIConnectButton.Text = 'Connect';
            app.WIFIDisconnectButton = uibutton(app.WifiCommunicationPanel, 'push');
            app.WIFIDisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @WIFIDisconnectButtonPushed, true);
            app.WIFIDisconnectButton.Text = 'Disconnect';
            app.IPEditFieldLabel = uilabel(app.WifiCommunicationPanel);
            app.IPEditFieldLabel.Text = 'IP';
            app.IPEditField = uieditfield(app.WifiCommunicationPanel, 'text');
            app.IPEditField.ValueChangedFcn = createCallbackFcn(app, @IPEditFieldValueChanged, true);
            app.IPEditField.Value = '147.27.122.100';
            app.PortEditFieldLabel = uilabel(app.WifiCommunicationPanel);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.WifiCommunicationPanel, 'numeric');
            app.PortEditField.ValueChangedFcn = createCallbackFcn(app, @PortEditFieldValueChanged, true);
            app.PortEditField.Value = 80;
            app.gui_status = uilabel(app.OptimizationGUIUIFigure);
            app.gui_status.Text = 'gui_status';
            app.RunOptimizationButton = uibutton(app.OptimizationGUIUIFigure, 'push');
            app.RunOptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @RunOptimizationButtonPushed, true);
            app.RunOptimizationButton.Text = 'Run Optimization';
            app.PLabel = uilabel(app.OptimizationGUIUIFigure);
            app.PLabel.Text = 'P    :';
            app.PPV_val = uilabel(app.OptimizationGUIUIFigure);
            app.PPV_val.Text = '0 kW';
            app.PVLabel = uilabel(app.OptimizationGUIUIFigure);
            app.PVLabel.Text = 'PV';
            app.PLabel_2 = uilabel(app.OptimizationGUIUIFigure);
            app.PLabel_2.Text = 'P    :';
            app.PSC_val = uilabel(app.OptimizationGUIUIFigure);
            app.PSC_val.Text = '0 kW';
            app.SCLabel = uilabel(app.OptimizationGUIUIFigure);
            app.SCLabel.Text = 'SC';
            app.PLabel_3 = uilabel(app.OptimizationGUIUIFigure);
            app.PLabel_3.Text = 'P      :';
            app.PBat_val = uilabel(app.OptimizationGUIUIFigure);
            app.PBat_val.Text = '0 kW';
            app.BATLabel = uilabel(app.OptimizationGUIUIFigure);
            app.BATLabel.Text = 'BAT';
            app.PLabel_4 = uilabel(app.OptimizationGUIUIFigure);
            app.PLabel_4.Text = 'P    :';
            app.PEV_val = uilabel(app.OptimizationGUIUIFigure);
            app.PEV_val.Text = '0 kW';
            app.EVLabel = uilabel(app.OptimizationGUIUIFigure);
            app.EVLabel.Text = 'EV';
            app.PLabel_5 = uilabel(app.OptimizationGUIUIFigure);
            app.PLabel_5.Text = 'P        :';
            app.Pgrid_val = uilabel(app.OptimizationGUIUIFigure);
            app.Pgrid_val.Text = '0 kW';
            app.GRIDLabel = uilabel(app.OptimizationGUIUIFigure);
            app.GRIDLabel.Text = 'GRID';
            app.SoCLabel = uilabel(app.OptimizationGUIUIFigure);
            app.SoCLabel.Text = 'SoC       :';
            app.BATLabel_2 = uilabel(app.OptimizationGUIUIFigure);
            app.BATLabel_2.Text = 'BAT';
            app.SoCLabel_2 = uilabel(app.OptimizationGUIUIFigure);
            app.SoCLabel_2.Text = 'SoC     :';
            app.EVLabel_2 = uilabel(app.OptimizationGUIUIFigure);
            app.EVLabel_2.Text = 'EV';
            app.SoC_bat_val = uilabel(app.OptimizationGUIUIFigure);
            app.SoC_bat_val.Text = '0%';
            app.SoC_ev_val = uilabel(app.OptimizationGUIUIFigure);
            app.SoC_ev_val.Text = '0%';
            app.PlotOptimizationButton = uibutton(app.OptimizationGUIUIFigure, 'push');
            app.PlotOptimizationButton.ButtonPushedFcn = createCallbackFcn(app, @PlotOptimizationButtonPushed, true);
            app.PlotOptimizationButton.Text = 'Plot Optimization';
            app.AdvancedSettingsPanel = uipanel(app.OptimizationGUIUIFigure);
            app.AdvancedSettingsPanel.Title = 'AdvancedSettings';
            app.PSOParametersPanel = uipanel(app.AdvancedSettingsPanel);
            app.PSOParametersPanel.Title = 'PSO Parameters';
            app.PSOParticlesEditFieldLabel = uilabel(app.PSOParametersPanel);
            app.PSOParticlesEditFieldLabel.Text = 'PSO Particles';
            app.PSOParticlesEditField = uieditfield(app.PSOParametersPanel, 'numeric');
            app.PSOParticlesEditField.ValueChangedFcn = createCallbackFcn(app, @PSOParticlesEditFieldValueChanged, true);
            app.PSOParticlesEditField.Value = 10;
            app.PSOEpochsEditFieldLabel = uilabel(app.PSOParametersPanel);
            app.PSOEpochsEditFieldLabel.Text = 'PSO Epochs';
            app.PSOEpochsEditField = uieditfield(app.PSOParametersPanel, 'numeric');
            app.PSOEpochsEditField.ValueDisplayFormat = '%.0f';
            app.PSOEpochsEditField.ValueChangedFcn = createCallbackFcn(app, @PSOEpochsEditFieldValueChanged, true);
            app.PSOEpochsEditField.Value = 500;
            app.SavefolderEditFieldLabel = uilabel(app.AdvancedSettingsPanel);
            app.SavefolderEditFieldLabel.Text = 'Save folder';
            app.SavefolderEditField = uieditfield(app.AdvancedSettingsPanel, 'text');
            app.SavefolderEditField.Value = 'C:\MATLAB\GUI_bin';
            app.IPofdataaggregatorEditFieldLabel = uilabel(app.AdvancedSettingsPanel);
            app.IPofdataaggregatorEditFieldLabel.Text = {'IP of data '; 'aggregator'};
            app.IPofdataaggregatorEditField = uieditfield(app.AdvancedSettingsPanel, 'text');
            app.IPofdataaggregatorEditField.Value = '192.168.1.67:80';
            app.Vdc_val = uilabel(app.OptimizationGUIUIFigure);
            app.Vdc_val.Text = '0 V';
            app.VLabel = uilabel(app.OptimizationGUIUIFigure);
            app.VLabel.Text = 'V    :';
            app.DCLabel = uilabel(app.OptimizationGUIUIFigure);
            app.DCLabel.Text = 'DC';
            app.Button_2 = uibutton(app.OptimizationGUIUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '?';
            % Show the figure after all components are created
            app.OptimizationGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UserInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OptimizationGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OptimizationGUIUIFigure)
        end
    end
end